classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PotentialTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Panel_33                        matlab.ui.container.Panel
        TipsLabel_2                     matlab.ui.control.Label
        CloseButton_7                   matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        SelectedActionDropDown          matlab.ui.control.DropDown
        SelectedActionDropDownLabel     matlab.ui.control.Label
        FitPotentialTypeDropDown        matlab.ui.control.DropDown
        FitPotentialTypeDropDownLabel   matlab.ui.control.Label
        SelectedPathEditField_4         matlab.ui.control.EditField
        SelectedPathEditField_4Label    matlab.ui.control.Label
        SelectedFolderEditField         matlab.ui.control.EditField
        SelectedFolderEditFieldLabel    matlab.ui.control.Label
        Panel_27                        matlab.ui.container.Panel
        ConfirmButton                   matlab.ui.control.Button
        SelectedFolderEditField_6       matlab.ui.control.EditField
        SelectedFolderEditField_6Label  matlab.ui.control.Label
        SelectFolderButton_6            matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel  matlab.ui.control.Label
        ClickTheGuideforhelpLabel_2     matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Panel_46                        matlab.ui.container.Panel
        CreatepotentialdataorfitanRGTFunsupportedpotentialtoyourdataLabel  matlab.ui.control.Label
        CalculateFitPotentialLabel      matlab.ui.control.Label
        SelectLocationofRGTFunFolderButton  matlab.ui.control.Button
        Panel_12                        matlab.ui.container.Panel
        Panel_13                        matlab.ui.container.Panel
        Panel_39                        matlab.ui.container.Panel
        rmguessAEditField               matlab.ui.control.NumericEditField
        rmguessAEditFieldLabel          matlab.ui.control.Label
        maxrmAEditField                 matlab.ui.control.NumericEditField
        maxrmAEditFieldLabel            matlab.ui.control.Label
        minrmAEditField                 matlab.ui.control.NumericEditField
        minrmAEditFieldLabel            matlab.ui.control.Label
        kguessEditField_2               matlab.ui.control.NumericEditField
        kguessEditField_2Label          matlab.ui.control.Label
        maxkEditField_2                 matlab.ui.control.NumericEditField
        maxkEditField_2Label            matlab.ui.control.Label
        minkEditField_2                 matlab.ui.control.NumericEditField
        minkEditField_2Label            matlab.ui.control.Label
        epsguesseVEditField             matlab.ui.control.NumericEditField
        epsguesseVEditFieldLabel        matlab.ui.control.Label
        maxepseVEditField               matlab.ui.control.NumericEditField
        maxepseVEditFieldLabel          matlab.ui.control.Label
        minepseVEditField               matlab.ui.control.NumericEditField
        minepseVEditFieldLabel          matlab.ui.control.Label
        SpecifyMorseParametersLabel     matlab.ui.control.Label
        Panel_19                        matlab.ui.container.Panel
        kguessEditField                 matlab.ui.control.NumericEditField
        kguessEditFieldLabel            matlab.ui.control.Label
        aguessEditField                 matlab.ui.control.NumericEditField
        aguessEditFieldLabel            matlab.ui.control.Label
        maxkEditField                   matlab.ui.control.NumericEditField
        maxkEditFieldLabel              matlab.ui.control.Label
        minkEditField                   matlab.ui.control.NumericEditField
        minkEditFieldLabel              matlab.ui.control.Label
        maxaEditField                   matlab.ui.control.NumericEditField
        maxaEditFieldLabel              matlab.ui.control.Label
        minaEditField                   matlab.ui.control.NumericEditField
        minaEditFieldLabel              matlab.ui.control.Label
        SpecifyPowerLawParametersLabel  matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        sigmaguessAEditField            matlab.ui.control.NumericEditField
        sigmaguessALabel                matlab.ui.control.Label
        welldepthguesseVEditField       matlab.ui.control.NumericEditField
        welldepthguesseVEditFieldLabel  matlab.ui.control.Label
        maxsigmaAEditField              matlab.ui.control.NumericEditField
        maxsigmaAEditFieldLabel         matlab.ui.control.Label
        minsigmaAEditField              matlab.ui.control.NumericEditField
        minsigmaAEditFieldLabel         matlab.ui.control.Label
        maxwelldeptheVEditField         matlab.ui.control.NumericEditField
        maxwelldeptheVEditFieldLabel    matlab.ui.control.Label
        minwelldeptheVEditField         matlab.ui.control.NumericEditField
        minwelldeptheVEditFieldLabel    matlab.ui.control.Label
        SpecifyLJParametersLabel_3      matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        minZ2EditField_2                matlab.ui.control.NumericEditField
        minZ2EditField_2Label           matlab.ui.control.Label
        maxZ2EditField_2                matlab.ui.control.NumericEditField
        maxZ2EditField_2Label           matlab.ui.control.Label
        Z1EditField_3                   matlab.ui.control.NumericEditField
        Z1EditField_3Label              matlab.ui.control.Label
        SpecifyCoulombZBLParametersLabel  matlab.ui.control.Label
        SelectedPathEditField_14        matlab.ui.control.EditField
        SelectedPathEditField_14Label   matlab.ui.control.Label
        SelectPotentialFileButton_2     matlab.ui.control.Button
        maxrAEditField                  matlab.ui.control.NumericEditField
        maxrALabel                      matlab.ui.control.Label
        minrAEditField                  matlab.ui.control.NumericEditField
        minrAEditFieldLabel             matlab.ui.control.Label
        toleranceEditField_2            matlab.ui.control.NumericEditField
        toleranceEditField_2Label       matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Panel_40                        matlab.ui.container.Panel
        rmAEditField                    matlab.ui.control.NumericEditField
        rmAEditFieldLabel               matlab.ui.control.Label
        keVA2EditField                  matlab.ui.control.NumericEditField
        keVA2EditFieldLabel             matlab.ui.control.Label
        welldeptheVEditField_2          matlab.ui.control.NumericEditField
        welldeptheVEditField_2Label     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Z2EditField                     matlab.ui.control.NumericEditField
        Z2EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        Panel_18                        matlab.ui.container.Panel
        kEditField                      matlab.ui.control.NumericEditField
        kAngstrom1Label                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        rstepAEditField                 matlab.ui.control.NumericEditField
        rstepAEditFieldLabel            matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SigmaAEditField                 matlab.ui.control.NumericEditField
        SigmaALabel                     matlab.ui.control.Label
        WellDeptheVEditField            matlab.ui.control.NumericEditField
        WellDeptheVEditFieldLabel       matlab.ui.control.Label
        minrAEditField_2                matlab.ui.control.NumericEditField
        minrAEditField_2Label           matlab.ui.control.Label
        maxrAEditField_2                matlab.ui.control.NumericEditField
        maxrAEditField_2Label           matlab.ui.control.Label
        SelectInputFileButton           matlab.ui.control.Button
        OrSelectYourOwnInputFileLabel   matlab.ui.control.Label
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel  matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        ExecuteButton                   matlab.ui.control.Button
        WriteInputFileButton            matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        LINEARTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        LOGTab                          matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        ScatteringTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        IntegralTypeDropDown            matlab.ui.control.DropDown
        IntegralTypeDropDownLabel       matlab.ui.control.Label
        SelectedFolderEditField_5       matlab.ui.control.EditField
        SelectedFolderEditField_5Label  matlab.ui.control.Label
        SelectedFileEditField           matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        ClickTheGuideforhelpLabel       matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        Panel_47                        matlab.ui.container.Panel
        CalculatethedistanceofclosestapproachDOCAandscatteringangleasafunctionofimpactparameterLabel  matlab.ui.control.Label
        CalculateScatteringIntegralsLabel  matlab.ui.control.Label
        Panel_45                        matlab.ui.container.Panel
        ConfirmButton_2                 matlab.ui.control.Button
        SelectedFolderEditField_13      matlab.ui.control.EditField
        SelectedFolderEditField_13Label  matlab.ui.control.Label
        SelectFolderButton_13           matlab.ui.control.Button
        PleaseselectlocationofChebfunFolderLabel  matlab.ui.control.Label
        Panel_28                        matlab.ui.container.Panel
        ConfirmButton_3                 matlab.ui.control.Button
        SelectedFolderEditField_7       matlab.ui.control.EditField
        SelectedFolderEditField_7Label  matlab.ui.control.Label
        SelectFolderButton_7            matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel_2  matlab.ui.control.Label
        SelectLocationofChebfunFolderButton  matlab.ui.control.Button
        Panel_32                        matlab.ui.container.Panel
        TipsLabel                       matlab.ui.control.Label
        CloseButton_6                   matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        SelectLocationofRGTFunFolderButton_2  matlab.ui.control.Button
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_2  matlab.ui.control.Label
        SelectFolderButton_5            matlab.ui.control.Button
        SelectScatteringInputFileButton  matlab.ui.control.Button
        OrselectyourownscatteringinputfileLabel  matlab.ui.control.Label
        WriteScatteringInputFileButton  matlab.ui.control.Button
        TabGroup2_3                     matlab.ui.container.TabGroup
        DistofClosestApproachTab        matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        ScatteringAngleTab              matlab.ui.container.Tab
        UIAxes_5                        matlab.ui.control.UIAxes
        ExecuteButton_3                 matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        Panel_24                        matlab.ui.container.Panel
        numberofptsEditField            matlab.ui.control.NumericEditField
        numberofptsEditFieldLabel       matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        EstepeVEditField                matlab.ui.control.NumericEditField
        EstepeVEditFieldLabel           matlab.ui.control.Label
        orLabel                         matlab.ui.control.Label
        LogspacingCheckBox              matlab.ui.control.CheckBox
        Panel_16                        matlab.ui.container.Panel
        Panel_37                        matlab.ui.container.Panel
        numberofptsEditField_3          matlab.ui.control.NumericEditField
        numberofptsEditField_3Label     matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        bstepAEditField                 matlab.ui.control.NumericEditField
        bstepAEditFieldLabel            matlab.ui.control.Label
        LogspacingCheckBox_3            matlab.ui.control.CheckBox
        maxRminAEditField               matlab.ui.control.NumericEditField
        maxRminALabel                   matlab.ui.control.Label
        minRminAEditField               matlab.ui.control.NumericEditField
        minRminALabel                   matlab.ui.control.Label
        SpecifyRootFindingRangeforRminLabel  matlab.ui.control.Label
        bmaxAEditField                  matlab.ui.control.NumericEditField
        bmaxAEditFieldLabel             matlab.ui.control.Label
        bminAEditField                  matlab.ui.control.NumericEditField
        bminAEditFieldLabel             matlab.ui.control.Label
        SelectedPathEditField_15        matlab.ui.control.EditField
        SelectedPathEditField_15Label   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        SpecifyFitOutputFileLabel       matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        thetaminradEditField            matlab.ui.control.NumericEditField
        thetaminradEditFieldLabel       matlab.ui.control.Label
        thetastepradEditField           matlab.ui.control.NumericEditField
        thetastepradEditFieldLabel      matlab.ui.control.Label
        thetamaxradEditField            matlab.ui.control.NumericEditField
        thetamaxradEditFieldLabel       matlab.ui.control.Label
        z2EditField                     matlab.ui.control.NumericEditField
        z2EditFieldLabel                matlab.ui.control.Label
        z1EditField                     matlab.ui.control.NumericEditField
        z1EditFieldLabel                matlab.ui.control.Label
        maxEeVEditField                 matlab.ui.control.NumericEditField
        maxEeVEditFieldLabel            matlab.ui.control.Label
        ScatteringIntegralsLabel        matlab.ui.control.Label
        minEeVEditField                 matlab.ui.control.NumericEditField
        minEeVEditFieldLabel            matlab.ui.control.Label
        CrossSectionsTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        IntegralTypeDropDown_2          matlab.ui.control.DropDown
        IntegralTypeDropDown_2Label     matlab.ui.control.Label
        SelectedFolderEditField_4       matlab.ui.control.EditField
        SelectedFolderEditField_4Label  matlab.ui.control.Label
        SelectedFileEditField_2         matlab.ui.control.EditField
        SelectedFileEditField_2Label    matlab.ui.control.Label
        ClickTheGuideforhelpLabel_3     matlab.ui.control.Label
        Image2_3                        matlab.ui.control.Image
        Panel_48                        matlab.ui.container.Panel
        CalculatetotalcrosssectiondiffusioncrosssectionviscositycrosssectionandstoppingcrosssectionLabel  matlab.ui.control.Label
        CalculateCrossSectionsLabel     matlab.ui.control.Label
        Panel_34                        matlab.ui.container.Panel
        TipsLabel_3                     matlab.ui.control.Label
        CloseButton_8                   matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        Panel_29                        matlab.ui.container.Panel
        ConfirmButton_4                 matlab.ui.control.Button
        SelectedFolderEditField_8       matlab.ui.control.EditField
        SelectedFolderEditField_8Label  matlab.ui.control.Label
        SelectFolderButton_8            matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel_3  matlab.ui.control.Label
        SelectLocationofRGTFunFolderButton_3  matlab.ui.control.Button
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_3  matlab.ui.control.Label
        SelectFolderButton_4            matlab.ui.control.Button
        SelectTransportInputFileButton  matlab.ui.control.Button
        OrselectyourowntransportinputfileLabel  matlab.ui.control.Label
        WriteCSInputFileButton          matlab.ui.control.Button
        ExecuteButton_4                 matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        Panel_42                        matlab.ui.container.Panel
        SelectedPathEditField_17        matlab.ui.control.EditField
        SelectDOCAfileButton            matlab.ui.control.Button
        Panel_41                        matlab.ui.container.Panel
        thetamaxradEditField_2          matlab.ui.control.NumericEditField
        thetamaxradEditField_2Label     matlab.ui.control.Label
        TotalCrossSectionCutoffDropDown  matlab.ui.control.DropDown
        TotalCrossSectionCutoffDropDownLabel  matlab.ui.control.Label
        Panel_25                        matlab.ui.container.Panel
        numberofptsEditField_2          matlab.ui.control.NumericEditField
        numberofptsEditField_2Label     matlab.ui.control.Label
        Panel_26                        matlab.ui.container.Panel
        EstepeVEditField_2              matlab.ui.control.NumericEditField
        EstepeVEditField_2Label         matlab.ui.control.Label
        orLabel_2                       matlab.ui.control.Label
        LogspacingCheckBox_2            matlab.ui.control.CheckBox
        m2amuEditField_2                matlab.ui.control.NumericEditField
        m2amuEditField_2Label           matlab.ui.control.Label
        m1amuEditField_2                matlab.ui.control.NumericEditField
        m1amuEditField_2Label           matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        SelectedPathEditField_16        matlab.ui.control.EditField
        SelectedPathEditField_16Label   matlab.ui.control.Label
        SelectPathButton_7              matlab.ui.control.Button
        SelectImpactParamvsScatterAngleDataFileLabel  matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        welldeptheVEditField            matlab.ui.control.NumericEditField
        welldeptheVEditFieldLabel       matlab.ui.control.Label
        maxEeVEditField_2               matlab.ui.control.NumericEditField
        maxEeVEditField_2Label          matlab.ui.control.Label
        TransportCrossSectionLabel      matlab.ui.control.Label
        minEeVEditField_2               matlab.ui.control.NumericEditField
        minEeVEditField_2Label          matlab.ui.control.Label
        TabGroup2_4                     matlab.ui.container.TabGroup
        TotalCrossSectionTab            matlab.ui.container.Tab
        UIAxes_15                       matlab.ui.control.UIAxes
        DiffusionCrossSectionTab        matlab.ui.container.Tab
        UIAxes_8                        matlab.ui.control.UIAxes
        ViscosityCrossSectionTab        matlab.ui.container.Tab
        UIAxes_9                        matlab.ui.control.UIAxes
        StoppingCrossSectionTab         matlab.ui.container.Tab
        UIAxes_13                       matlab.ui.control.UIAxes
        TransportTab                    matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        IntegrationTypeDropDown         matlab.ui.control.DropDown
        IntegrationTypeDropDownLabel    matlab.ui.control.Label
        SelectedFolderEditField_3       matlab.ui.control.EditField
        SelectedFolderEditField_3Label  matlab.ui.control.Label
        SelectedFileEditField_3         matlab.ui.control.EditField
        SelectedFileEditField_3Label    matlab.ui.control.Label
        ClickTheGuideforhelpLabel_4     matlab.ui.control.Label
        Image2_4                        matlab.ui.control.Image
        Panel_49                        matlab.ui.container.Panel
        CalculatetheselfdiffusioncoefficientandviscositycoefficientforuserspecifiedtemperaturesLabel  matlab.ui.control.Label
        CalculateTransportCoefficientsLabel  matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        TipsLabel_4                     matlab.ui.control.Label
        CloseButton_9                   matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        Panel_30                        matlab.ui.container.Panel
        ConfirmButton_5                 matlab.ui.control.Button
        SelectedFolderEditField_9       matlab.ui.control.EditField
        SelectedFolderEditField_9Label  matlab.ui.control.Label
        SelectFolderButton_9            matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel_4  matlab.ui.control.Label
        SelectLocationofRGTFunFolderButton_4  matlab.ui.control.Button
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_4  matlab.ui.control.Label
        TabGroup2_5                     matlab.ui.container.TabGroup
        DiffusionTab                    matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        ViscosityTab                    matlab.ui.container.Tab
        UIAxes_16                       matlab.ui.control.UIAxes
        SelectFolderButton_3            matlab.ui.control.Button
        SelectTransportInputFileButton_2  matlab.ui.control.Button
        OrselectyourowntransportinputfileLabel_2  matlab.ui.control.Label
        WriteTransportInputFileButton_2  matlab.ui.control.Button
        ExecuteButton_5                 matlab.ui.control.Button
        Panel_9                         matlab.ui.container.Panel
        Panel_22                        matlab.ui.container.Panel
        dangstromEditField              matlab.ui.control.NumericEditField
        dangstromEditFieldLabel         matlab.ui.control.Label
        welldepthKEditField             matlab.ui.control.NumericEditField
        welldepthKEditFieldLabel        matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        SelectedFileEditField_7         matlab.ui.control.EditField
        SelectedFileEditField_7Label    matlab.ui.control.Label
        SelectViscosityCSDataButton     matlab.ui.control.Button
        SelectedFileEditField_4         matlab.ui.control.EditField
        SelectedFileEditField_4Label    matlab.ui.control.Label
        SelectDiffusionCSDataButton     matlab.ui.control.Button
        m2amuEditField                  matlab.ui.control.NumericEditField
        m2amuEditFieldLabel             matlab.ui.control.Label
        m1amuEditField                  matlab.ui.control.NumericEditField
        m1amuEditFieldLabel             matlab.ui.control.Label
        TstepKEditField                 matlab.ui.control.NumericEditField
        TstepKEditFieldLabel            matlab.ui.control.Label
        maxTKEditField                  matlab.ui.control.NumericEditField
        maxTKEditFieldLabel             matlab.ui.control.Label
        minTKEditField                  matlab.ui.control.NumericEditField
        minTKEditFieldLabel             matlab.ui.control.Label
        VHSTab                          matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        m2amuEditField_3                matlab.ui.control.NumericEditField
        m2amuEditField_3Label           matlab.ui.control.Label
        m1amuEditField_3                matlab.ui.control.NumericEditField
        m1amuEditField_3Label           matlab.ui.control.Label
        tolEditField                    matlab.ui.control.NumericEditField
        tolEditFieldLabel               matlab.ui.control.Label
        SelectedFileEditField_6         matlab.ui.control.EditField
        SelectedFileEditField_6Label    matlab.ui.control.Label
        SelectedFolderEditField_2       matlab.ui.control.EditField
        SelectedFolderEditField_2Label  matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        SelectedFileEditField_5         matlab.ui.control.EditField
        SelectedFileEditField_5Label    matlab.ui.control.Label
        ClickTheGuideforhelpLabel_5     matlab.ui.control.Label
        Image2_5                        matlab.ui.control.Image
        Panel_50                        matlab.ui.container.Panel
        CalculatesDSMCparametersfortheVHSmodelLabel  matlab.ui.control.Label
        CalculateVHSParametersLabel     matlab.ui.control.Label
        Panel_36                        matlab.ui.container.Panel
        TipsLabel_5                     matlab.ui.control.Label
        CloseButton_10                  matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        Panel_31                        matlab.ui.container.Panel
        ConfirmButton_6                 matlab.ui.control.Button
        SelectedFolderEditField_10      matlab.ui.control.EditField
        SelectedFolderEditField_10Label  matlab.ui.control.Label
        SelectFolderButton_10           matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel_5  matlab.ui.control.Label
        SelectLocationofRGTFunFolderButton_5  matlab.ui.control.Button
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_5  matlab.ui.control.Label
        TabGroup2_6                     matlab.ui.container.TabGroup
        ViscosityCoefficientTab         matlab.ui.container.Tab
        UIAxes_12                       matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        SelectVHSCoefInputFileButton    matlab.ui.control.Button
        OrselectyourownVHScoefinputfileLabel  matlab.ui.control.Label
        ExecuteButton_6                 matlab.ui.control.Button
        SpecifyNumberofSubintervalsLabel  matlab.ui.control.Label
        SelectFolderButton_2            matlab.ui.control.Button
        WriteVHSInputFileButton         matlab.ui.control.Button
        SelectViscosityCoefDataButton   matlab.ui.control.Button
        VSSTab                          matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        m2amuEditField_4                matlab.ui.control.NumericEditField
        m2amuEditField_4Label           matlab.ui.control.Label
        m1amuEditField_4                matlab.ui.control.NumericEditField
        m1amuEditField_4Label           matlab.ui.control.Label
        alphaguessEditField             matlab.ui.control.NumericEditField
        alphaguessEditFieldLabel        matlab.ui.control.Label
        SelectedFileEditField_11        matlab.ui.control.EditField
        SelectedFileEditField_11Label   matlab.ui.control.Label
        SelectedFileEditField_10        matlab.ui.control.EditField
        SelectedFileEditField_10Label   matlab.ui.control.Label
        leastsquarestolEditField        matlab.ui.control.NumericEditField
        leastsquarestolEditFieldLabel   matlab.ui.control.Label
        SelectedFileEditField_9         matlab.ui.control.EditField
        SelectedFileEditField_9Label    matlab.ui.control.Label
        SelectedFolderEditField_11      matlab.ui.control.EditField
        SelectedFolderEditField_11Label  matlab.ui.control.Label
        ClickTheGuideforhelpLabel_6     matlab.ui.control.Label
        Image2_6                        matlab.ui.control.Image
        Panel_51                        matlab.ui.container.Panel
        CalculatesDSMCparametersfortheVSSmodelLabel  matlab.ui.control.Label
        CalculateVSSParametersLabel     matlab.ui.control.Label
        SelectVHSCoefTableButton        matlab.ui.control.Button
        SelectDiffusionCoefDataButton   matlab.ui.control.Button
        Panel_44                        matlab.ui.container.Panel
        TipsLabel_6                     matlab.ui.control.Label
        CloseButton_12                  matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        Panel_43                        matlab.ui.container.Panel
        ConfirmButton_7                 matlab.ui.control.Button
        SelectedFolderEditField_12      matlab.ui.control.EditField
        SelectedFolderEditField_12Label  matlab.ui.control.Label
        SelectFolderButton_12           matlab.ui.control.Button
        PleaseselectlocationofRGTFunFolderLabel_6  matlab.ui.control.Label
        SelectLocationofRGTFunFolderButton_6  matlab.ui.control.Button
        SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_6  matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        SelectVSSCoefInputFileButton    matlab.ui.control.Button
        OrselectyourownVSScoefinputfileLabel  matlab.ui.control.Label
        ExecuteButton_7                 matlab.ui.control.Button
        SelectFolderButton_11           matlab.ui.control.Button
        WriteVSSInputFileButton         matlab.ui.control.Button
    end

    %these are properties that I utilize across different callbacks
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        minE
        maxE
        Estep
        rgtfunpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
            app.Panel.Visible='off';
            app.Panel_2.Visible='off';
            app.Panel_11.Visible='off';
            app.Panel_6.Visible = 'off';
            app.Panel_13.Visible = 'off';
            app.Panel_14.Visible = 'off';
            app.Panel_15.Visible = 'off';
            app.Panel_17.Visible = 'off';
            app.Panel_16.Visible = 'off';
            app.Panel_18.Visible = 'off';
            app.Panel_19.Visible = 'off';
            app.Panel_20.Visible = 'off';
            app.Panel_21.Visible = 'off';
            app.Panel_22.Visible = 'off';
            app.Panel_24.Visible = 'off';
            app.Panel_25.Visible = 'off';
            app.Panel_27.Visible = 'on';
            app.Panel_28.Visible = 'on';
            app.Panel_29.Visible = 'on';
            app.Panel_30.Visible = 'on';
            app.Panel_31.Visible = 'on';
            app.Panel_32.Visible = 'off';
            app.Panel_33.Visible = 'off';
            app.Panel_34.Visible = 'off';
            app.Panel_35.Visible = 'off';
            app.Panel_36.Visible = 'off';
            app.Panel_37.Visible = 'off';
            app.Panel_39.Visible = 'off';
            app.Panel_40.Visible = 'off';
            app.Panel_41.Visible = 'off';
            app.Panel_42.Visible = 'off';
            app.Panel_44.Visible = 'off';
            app.Panel_45.Visible = 'on';


        end

        % Callback function
        function FitPotentialTypeDropDownClicked(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'12-6 Lennard-Jones') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'12-4 Lennard-Jones')
                    app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Morse')
                    app.Panel_40.Visible='on'; 
                    app.Panel_2.Visible='off'; 
                    app.Panel.Visible='off';
                    app.Panel_18.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_18.Visible = 'on';
                    app.Panel.Visible = 'off';
                    app.Panel_2.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                else 
                    app.Panel.Visible='off';
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                end
            
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel_14.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'12-6 Lennard-Jones') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'12-4 Lennard-Jones')
                    app.Panel_15.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_14.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Morse')
                    app.Panel_39.Visible='on'; 
                    app.Panel_19.Visible = 'off';
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_19.Visible = 'on';
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                    app.Panel_39.Visible='off'; 
                else 
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                end
            end
            
     
        end

        % Callback function
        function ExecuteButtonPushed(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                y = run_calcpotential(app.SelectedPathEditField_4.Value,...
                app.SelectedFolderEditField.Value);
                t = readmatrix(y);
                xvals = t(:,1);
                yvals = t(:,2);
                plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
                plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
                yscale(app.UIAxes_2,'log');
                xscale(app.UIAxes_2,'linear');
                title(app.UIAxes,'Interatomic Potential');
                title(app.UIAxes_2,'Interatomic Potential');
                ylim(app.UIAxes,[-10,10])
                %app.SelectedPathEditField.Value = y;
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                run(app.SelectedPathEditField_4.Value);
                datalocation = app.SelectedPathEditField_14.Value;
                t = readmatrix(datalocation);
                myfit = run_fitpotential(app.SelectedPathEditField_4.Value,...
                    app.SelectedPathEditField_14.Value);
                coefs = coeffvalues(myfit);
                disp(coefs(1))
                disp(coefs(2))
                outputfilename  = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
                outputfile=fopen(outputfilename,'w');
                fprintf(outputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(outputfile, 'minR = %f;\n',minR);
                fprintf(outputfile, 'maxR = %f;\n',maxR);
                fprintf(outputfile, 'Tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, "Coulomb") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(outputfile, 'Z1 = %i;\n',Z1);
                    fprintf(outputfile,'z2_param = %f;\n',coefs);
                    fprintf(outputfile, 'minZ2 = %i;\n',minZ2);
                    fprintf(outputfile, 'maxZ2 = %i;\n',maxZ2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(outputfile,'eps_param = %f;\n',coefs(1));
                    fprintf(outputfile,'sigma_param = %f;\n',coefs(2));
                    fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(outputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(outputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(outputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    fprintf(outputfile,'rm_param = %f;\n',coefs(1));
                    fprintf(outputfile,'eps_param = %f;\n',coefs(2));
                    fprintf(outputfile,'k_param = %f;\n',coefs(3));
                    fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(outputfile, 'min_k = %f;\n',min_k);
                    fprintf(outputfile, 'max_k = %f;\n',max_k);
                    fprintf(outputfile, 'min_rm = %f;\n',min_rm);
                    fprintf(outputfile, 'max_rm = %f;\n',max_rm);
                    fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(outputfile, 'k_start = %f;\n',k_start);
                    fprintf(outputfile, 'rm_start = %f;\n',rm_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(outputfile,'a_param = %f;\n',coefs(1));
                    fprintf(outputfile,'k_param = %f;\n',coefs(2));
                    fprintf(outputfile, 'min_a = %f;\n',min_a);
                    fprintf(outputfile, 'max_a = %f;\n',max_a);
                    fprintf(outputfile, 'min_k = %f;\n',min_k);
                    fprintf(outputfile, 'max_k = %f;\n',max_k);
                    fprintf(outputfile, 'a_start = %f;\n',a_start);
                    fprintf(outputfile, 'k_start = %f;\n',k_start);
                end
                fclose(outputfile);
                excludex = ((t(:,1) < minR) | (t(:,1) > maxR));
                xvals = t(:,1);
                yvals = t(:,2);
                xvals(excludex) = [];
                yvals(excludex) = [];
               
                plot(app.UIAxes,myfit,xvals,yvals);
                title(app.UIAxes,'Fitted Interatomic Potential');
                % plot(app.UIAxes_4,myfit,xvals,yvals);
                % yscale(app.UIAxes_4,'log');
                % xscale(app.UIAxes_4,'linear');
            end
            
            % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes, fullfile(outputDir, 'PotentialPlot.png'));
            app.SelectedPathEditField_15.Value = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
            app.SelectedFolderEditField_5.Value = app.SelectedFolderEditField.Value;
      

        end

        % Callback function
        function WriteInputFileButtonPushed2(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                minr = app.minrAEditField_2.Value;
                maxr = app.maxrAEditField_2.Value;
                rstep = app.rstepAEditField.Value;
                z1 = app.Z1EditField.Value;
                z2 = app.Z2EditField.Value;
                a = app.aEditField.Value;
                k = app.kEditField.Value;
                well_eps = app.WellDeptheVEditField.Value;
                sig = app.SigmaAEditField.Value;
                filename  = [app.SelectedFolderEditField.Value '/potentialinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile, 'Rstep = %f;\n',rstep);
                if strcmp(app.FitPotentialTypeDropDown.Value,"Coulomb") | ...
                    strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'Z2 = %i;\n',z2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(inputfile, 'eps_well = %f;\n',well_eps);
                    fprintf(inputfile, 'sig = %i;\n',sig);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    eps_well = app.welldeptheVEditField_2.Value;
                    k = app.keVA2EditField.Value;
                    rm = app.rmAEditField.Value;
                    fprintf(inputfile, 'eps_well = %f;\n',eps_well);
                    fprintf(inputfile, 'k = %f;\n',k);
                    fprintf(inputfile, 'rm = %f;\n',rm);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'a = %f;\n',a);
                    fprintf(inputfile, 'k = %f;\n',k);
                end
                fclose(inputfile);

                outputfilename  = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
                outputfile=fopen(outputfilename,'w');
                fprintf(outputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(outputfile, 'minR = %f;\n',minr);
                fprintf(outputfile, 'maxR = %f;\n',maxr);
                if strcmp(app.FitPotentialTypeDropDown.Value, "Coulomb") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(outputfile, 'Z1 = %i;\n',z1);
                    fprintf(outputfile,'z2_param = %i;\n',z2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(outputfile,'eps_param = %f;\n',well_eps);
                    fprintf(outputfile,'sigma_param = %f;\n',sig);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    eps_well = app.welldeptheVEditField_2.Value;
                    k = app.keVA2EditField.Value;
                    rm = app.rmAEditField.Value;
                    fprintf(outputfile,'eps_param = %f;\n',eps_well);
                    fprintf(outputfile,'k_param = %f;\n',k);
                    fprintf(outputfile,'rm_param = %f;\n',rm);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(outputfile,'a_param = %f;\n',a);
                    fprintf(outputfile,'k_param = %f;\n',k);
                end
                fclose(outputfile);
                
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                z1 = app.Z1EditField_3.Value;
                minr = app.minrAEditField.Value;
                maxr = app.maxrAEditField.Value;
                tol = app.toleranceEditField_2.Value;
                minz2 = app.minZ2EditField_2.Value;
                maxz2 = app.maxZ2EditField_2.Value;
                min_eps = app.minwelldeptheVEditField.Value;
                max_eps = app.maxwelldeptheVEditField.Value;
                min_sigma = app.minsigmaAEditField.Value;
                max_sigma = app.maxsigmaAEditField.Value;
                eps_start = app.welldepthguesseVEditField.Value;
                sigma_start = app.sigmaguessAEditField.Value;
                k_start = app.kguessEditField.Value;
                a_start = app.aguessEditField.Value;
                maxa = app.maxaEditField.Value;
                mina = app.minaEditField.Value;
                maxk = app.maxkEditField.Value;
                mink = app.minkEditField.Value;
                filename  = [app.SelectedFolderEditField.Value '/fitinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile,'tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, 'Coulomb') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'minZ2 = %i;\n',minz2);
                    fprintf(inputfile, 'maxZ2 = %i;\n',maxz2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(inputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(inputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(inputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(inputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    min_eps = app.minepseVEditField.Value;
                    max_eps = app.maxepseVEditField.Value;
                    eps_guess = app.epsguesseVEditField.Value;
                    min_k = app.minkEditField_2.Value;
                    max_k = app.maxkEditField_2.Value;
                    k_guess = app.kguessEditField_2.Value;
                    min_rm = app.minrmAEditField.Value;
                    max_rm = app.maxrmAEditField.Value;
                    rm_guess = app.rmguessAEditField.Value;
                    fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(inputfile, 'min_k = %f;\n',min_k);
                    fprintf(inputfile, 'max_k = %f;\n',max_k);
                    fprintf(inputfile, 'min_rm = %f;\n',min_rm);
                    fprintf(inputfile, 'max_rm = %f;\n',max_rm);
                    fprintf(inputfile, 'eps_start = %f;\n',eps_guess);
                    fprintf(inputfile, 'k_start = %f;\n',k_guess);
                    fprintf(inputfile, 'rm_start = %f;\n',rm_guess);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'min_a = %f;\n',mina);
                    fprintf(inputfile, 'max_a = %f;\n',maxa);
                    fprintf(inputfile, 'min_k = %f;\n',mink);
                    fprintf(inputfile, 'max_k = %f;\n',maxk);
                    fprintf(inputfile, 'k_start = %f;\n', k_start);
                    fprintf(inputfile, 'a_start = %f;\n', a_start);
                end
                fclose(inputfile);
            end
         app.SelectedPathEditField_4.Value = filename;
        end

        % Callback function
        function SelectInputFileButtonPushed2(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.SelectedPathEditField_4.Value = fullfile(folder, baseName);
        end

        % Callback function
        function SelectFolderButtonPushed(app, event)
            app.SelectedFolderEditField.Value = uigetdir;
        end

        % Button pushed function: ExecuteButton_3
        function ExecuteButton_3Pushed(app, event)
            srcpath = [app.rgtfunpath '/src'];
            %chebfunpath = [app.rgtfunpath '/src/chebfun'];
            addpath(srcpath)
            %addpath(chebfunpath)

            % Initialize progress dialog
            progressBar = uiprogressdlg(app.UIFigure, 'Title', 'Processing', ...
                'Message', 'Initializing...', 'Indeterminate', 'off');
            try
                % Run scattering integrals with progress bar
                y = run_scatteringintegrals(app.SelectedFileEditField.Value, ...
                    app.SelectedFolderEditField_5.Value, progressBar);
                % Update message upon completion
                progressBar.Message = 'Calculations completed!';
                progressBar.Value = 1;
                pause(1); % Give users a moment to see the "completed" message
            catch ME
                % Handle errors and ensure progress dialog is closed
                progressBar.Message = 'An error occurred!';
                rethrow(ME);
            end
            % Close progress dialog
            close(progressBar);

            run(app.SelectedFileEditField.Value);
            if logspace_on == 1
                minElog = log10(minE);
                maxElog = log10(maxE);
                Evals = logspace(minElog,maxElog,app.numberofptsEditField.Value);
            elseif logspace_on == 0
                Evals = minE:Estep:maxE;
            end
            hold(app.UIAxes_5,"on");
            %hold(app.UIAxes_6,"on");
            hold(app.UIAxes_7,"on");
            cla(app.UIAxes_5);
            %cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            axlegend = {};
            docadatapath  = [y '/docadata.csv'];
            tdoca = readmatrix(docadatapath);
            numESteps = numel(Evals);
            for i = 1:numESteps
                E = Evals(i);
                axlegend  = [axlegend sprintf('%f eV',E)];
                %disp(axlegend);
                
                if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb')
                    xvals3 = tdoca(:,2*i-1);
                    yvals3 = tdoca(:,2*i);
                    plot(app.UIAxes_7,xvals3,yvals3,'-o');
                    yscale(app.UIAxes_7,'log');
                    xscale(app.UIAxes_7,'log');
                    xscale(app.UIAxes_5,'log');
                    %difscatterdatapath  = [y sprintf('/difscatterdata/difscatterdata_%f.csv',E)];
                    impactdatapath  = [y '/impactparamdata.csv'];
                    %tdif = readmatrix(difscatterdatapath);
                    timpact = readmatrix(impactdatapath);
                    %xvals1 = tdif(:,1);
                    %yvals1 = tdif(:,2);
                    xvals2 = timpact(:,i+1);
                    yvals2 = timpact(:,1);
                    %plot(app.UIAxes_5,xvals1,yvals1,'-o');
                    plot(app.UIAxes_5,xvals2,yvals2,'-o');
                    title(app.UIAxes_5,'Scattering Angle')
                    ylabel(app.UIAxes_5,'Scattering Angle (rad)')
                    xlabel(app.UIAxes_5,'Impact Parameter (Angstrom)')
                elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical')
                    xvals3 = tdoca(:,1);
                    yvals3 = tdoca(:,1+i);
                    plot(app.UIAxes_7,xvals3,yvals3,'-o');
                    yscale(app.UIAxes_7,'log');
                    xscale(app.UIAxes_7,'log');
                    xscale(app.UIAxes_5,'log');
                    scatterangdatapath = [y '/scatterangledata.csv'];
                    ttheta = readmatrix(scatterangdatapath);
                    xvals1 = ttheta(:,1);
                    yvals1 = ttheta(:,i+1);
                    plot(app.UIAxes_5,xvals1,yvals1,'-o');
                    title(app.UIAxes_5,'Scattering Angle')
                    xlabel(app.UIAxes_5,'Impact Parameter (Angstrom)')
                    ylabel(app.UIAxes_5,'Scattering Angle (rad)')
                end
            end
           legend(app.UIAxes_5,axlegend);
           legend(app.UIAxes_7,axlegend);
           %legend(app.UIAxes_6,axlegend);
           app.SelectedFolderEditField_4.Value = app.SelectedFolderEditField_5.Value;
           app.minEeVEditField_2.Value = app.minEeVEditField.Value;
           app.maxEeVEditField_2.Value = app.maxEeVEditField.Value;
           app.EstepeVEditField_2.Value = app.EstepeVEditField.Value;
           app.numberofptsEditField_2.Value = app.numberofptsEditField.Value;
           app.LogspacingCheckBox_2.Value = app.LogspacingCheckBox.Value;
           if app.LogspacingCheckBox.Value == 1
               app.Panel_25.Visible = 'on';
               app.Panel_26.Visible = 'off';
           end


           % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField_5.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes_7, fullfile(outputDir, 'DOCAPlot.png'));
            exportgraphics(app.UIAxes_5, fullfile(outputDir, 'ScatterAnglePlot.png'));
            
            app.SelectedPathEditField_16.Value = [y '/scatterangledata.csv'];
            app.SelectedPathEditField_17.Value = [y '/docadata.csv'];

     
        end

        % Button pushed function: SelectScatteringInputFileButton
        function SelectScatteringInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.SelectedFileEditField.Value = fullfile(folder, baseName);
        end

        % Button pushed function: WriteScatteringInputFileButton
        function WriteScatteringInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_calcpotential, plots data, pushes data
            %location to next tab
            app.minE = app.minEeVEditField.Value;
            app.maxE = app.maxEeVEditField.Value;
            app.Estep = app.EstepeVEditField.Value;
            logstep = app.numberofptsEditField.Value;
            blogstep = app.numberofptsEditField_3.Value;
            filename  = [app.SelectedFolderEditField_5.Value '/scatterintinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'inttype = "%s";\n',app.IntegralTypeDropDown.Value);
            fprintf(inputfile, 'minE = %f;\n',app.minE);
            fprintf(inputfile, 'maxE = %f;\n',app.maxE);
            fprintf(inputfile, 'Estep = %f;\n',app.Estep);
            fprintf(inputfile, 'logspace_on = %i;\n',app.LogspacingCheckBox.Value);
            if app.LogspacingCheckBox.Value == 1
                fprintf(inputfile, 'logstep = %i;\n',logstep);
            end
            if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb')
                z1 = app.z1EditField.Value;            
                z2 = app.z2EditField.Value;
                theta_min = app.thetaminradEditField.Value;
                theta_max = app.thetamaxradEditField.Value;
                theta_step = app.thetastepradEditField.Value;
                fprintf(inputfile, 'Z1 = %i;\n',z1);
                fprintf(inputfile,'Z2 = %i;\n',z2);
                fprintf(inputfile,'theta_min = %f; \n',theta_min);
                fprintf(inputfile,'theta_max = %f; \n',theta_max);
                fprintf(inputfile,'theta_step = %f; \n',theta_step);
            elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical')
                chebfunpath = app.SelectedFolderEditField_13.Value;
                bmin = app.bminAEditField.Value;
                bmax = app.bmaxAEditField.Value;
                bstep = app.bstepAEditField.Value;
                minroot = app.minRminAEditField.Value;
                maxroot = app.maxRminAEditField.Value;
                fitfile = app.SelectedPathEditField_15.Value;
                fprintf(inputfile,'chebfunpath = "%s";\n',chebfunpath);
                fprintf(inputfile,'fitfile = "%s";\n',fitfile);
                fprintf(inputfile, 'blogspace_on = %i;\n',app.LogspacingCheckBox_3.Value);
                if app.LogspacingCheckBox_3.Value == 1
                    fprintf(inputfile, 'blogstep = %i;\n',blogstep);
                end
                fprintf(inputfile,'bmin = %f; \n',bmin);
                fprintf(inputfile,'bmax = %f; \n',bmax);
                fprintf(inputfile,'bstep = %f; \n',bstep);
                fprintf(inputfile,'minroot = %f; \n',minroot);
                fprintf(inputfile,'maxroot = %f; \n',maxroot);
            end
            fclose(inputfile);
            app.SelectedFileEditField.Value = filename;
        end

        % Button pushed function: SelectFolderButton_5
        function SelectFolderButton_5Pushed(app, event)
            app.SelectedFolderEditField_5.Value = uigetdir;
        end

        % Button pushed function: SelectFolderButton_4
        function SelectFolderButton_4Pushed(app, event)
            app.SelectedFolderEditField_4.Value = uigetdir;
        end

        % Button pushed function: WriteCSInputFileButton
        function WriteCSInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_transportCS, plots data, pushes data
            %location to next tab
            min_E = app.minEeVEditField_2.Value;
            max_E = app.maxEeVEditField_2.Value;
            E_step = app.EstepeVEditField_2.Value;
            m1 = app.m1amuEditField_2.Value;
            m2 = app.m2amuEditField_2.Value;
            th_max = app.thetamaxradEditField_2.Value;
            logstep = app.numberofptsEditField_2.Value;
            filename  = [app.SelectedFolderEditField_4.Value '/transportinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'inttype = "%s";\n',app.IntegralTypeDropDown_2.Value);
            fprintf(inputfile, 'minE = %f;\n',min_E);
            fprintf(inputfile, 'maxE = %f;\n',max_E);
            fprintf(inputfile, 'Estep = %f;\n',E_step);
            fprintf(inputfile, 'logspace_on = %i;\n',app.LogspacingCheckBox_2.Value);
            if app.LogspacingCheckBox_2.Value == 1
                fprintf(inputfile, 'logstep = %i;\n',logstep);
            end
            fprintf(inputfile, 'm1 = %f;\n',m1);
            fprintf(inputfile, 'm2 = %f;\n',m2);
            fprintf(inputfile, 'thetacutoff = "%s";\n',app.TotalCrossSectionCutoffDropDown.Value);
            if strcmp(app.TotalCrossSectionCutoffDropDown.Value,'Manual')
                fprintf(inputfile, 'th_max = %f;\n',th_max);
            elseif strcmp(app.TotalCrossSectionCutoffDropDown.Value,'Quantum')
                fprintf(inputfile,'docafile = "%s";\n',app.SelectedPathEditField_17.Value);
            end
            if strcmp(app.IntegralTypeDropDown_2.Value,'Exact LJ')
                welldepth = app.welldeptheVEditField.Value;
                fprintf(inputfile,'welldepth = %f; \n',welldepth);


            elseif strcmp(app.IntegralTypeDropDown_2.Value,'Numerical')
                datafilepath = app.SelectedPathEditField_16.Value;
                fprintf(inputfile,'datafile = "%s";\n',datafilepath);
            end
            fclose(inputfile);
            app.SelectedFileEditField_2.Value = filename;

        end

        % Button pushed function: SelectTransportInputFileButton
        function SelectTransportInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.SelectedFileEditField_2.Value = fullfile(folder, baseName);
        end

        % Button pushed function: ExecuteButton_4
        function ExecuteButton_4Pushed(app, event)
            
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            run_transportCS(app.SelectedFileEditField_2.Value,...
                app.SelectedFolderEditField_4.Value);
            run(app.SelectedFileEditField_2.Value);
            diffusioncsdatapath  = [app.SelectedFolderEditField_4.Value '/diffusioncsdata.csv'];
            viscositycsdatapath  = [app.SelectedFolderEditField_4.Value '/viscositycsdata.csv'];
            tdiffusion = readmatrix(diffusioncsdatapath);
            tvisc = readmatrix(viscositycsdatapath);
            xvals1 = tdiffusion(:,1);
            yvals1 = tdiffusion(:,2);        
            xvals2 = tvisc(:,1);
            yvals2 = tvisc(:,2);
            plot(app.UIAxes_8,xvals1,yvals1,'-o');
            plot(app.UIAxes_9,xvals2,yvals2,'-o');
            xlabel(app.UIAxes_8,'CM Energy (eV)');
            ylabel(app.UIAxes_8,'Cross Section (Angstrom^2)')
            xlabel(app.UIAxes_9,'CM Energy (eV)');
            ylabel(app.UIAxes_9,'Cross Section (Angstrom^2)')
            yscale(app.UIAxes_8,'log');
            xscale(app.UIAxes_8,'log');
            yscale(app.UIAxes_9,'log');
            xscale(app.UIAxes_9,'log');
            
            if strcmp(app.IntegralTypeDropDown_2.Value,'Numerical')
                stoppingcsdatapath  = [app.SelectedFolderEditField_4.Value '/stoppingcsdata.csv'];
                totalcsdatapath  = [app.SelectedFolderEditField_4.Value '/totalcsdata.csv'];
                tstop = readmatrix(stoppingcsdatapath);
                ttotal = readmatrix(totalcsdatapath);
                xvals3 = tstop(:,1);
                yvals3 = tstop(:,2);
                xvals4 = ttotal(:,1);
                yvals4 = ttotal(:,2);
                plot(app.UIAxes_13,xvals3,yvals3,'-o');
                plot(app.UIAxes_15,xvals4,yvals4,'-o');
                xlabel(app.UIAxes_13,'Lab Energy (eV)');
                ylabel(app.UIAxes_13,'Stopping Cross Section (eV Angstrom^2)')
                xlabel(app.UIAxes_15,'CM Energy (eV)');
                ylabel(app.UIAxes_15,'Cross Section (Angstrom^2)')
                yscale(app.UIAxes_13,'log');
                xscale(app.UIAxes_13,'log');
                yscale(app.UIAxes_15,'log');
                xscale(app.UIAxes_15,'log');
            end

            %axes(app.UIAxes_8,'XScale', 'log', 'YScale', 'linear')
            %hold(app.UIAxes_8,"on");
            %axes(app.UIAxes_9,'XScale', 'log', 'YScale', 'linear')
            %hold(app.UIAxes_9,"on");
            %axes(app.UIAxes_13,'XScale', 'log', 'YScale', 'linear')
            %hold(app.UIAxes_13,"on");
            %axes(app.UIAxes_15,'XScale', 'log', 'YScale', 'linear')
            %hold(app.UIAxes_15,"on");
           
            % xlim(app.UIAxes_8,[0,100]);
            % xlim(app.UIAxes_9,[0,100]);
            
            
            % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField_4.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes_8, fullfile(outputDir, 'DiffusionCSPlot.png'));
            exportgraphics(app.UIAxes_9, fullfile(outputDir, 'ViscosityCSPlot.png'));
            if strcmp(app.IntegralTypeDropDown_2.Value,'Numerical')
                exportgraphics(app.UIAxes_15, fullfile(outputDir, 'TotalCSPlot.png'));
                exportgraphics(app.UIAxes_13, fullfile(outputDir, 'StoppingCSPlot.png'));
            end
            app.SelectedFolderEditField_3.Value = app.SelectedFolderEditField_4.Value;

            app.m1amuEditField.Value = app.m1amuEditField_2.Value;
            app.m2amuEditField.Value = app.m2amuEditField_2.Value;
         
            app.SelectedFileEditField_4.Value = [app.SelectedFolderEditField_4.Value '/diffusioncsdata.csv'];
            app.SelectedFileEditField_7.Value = [app.SelectedFolderEditField_4.Value '/viscositycsdata.csv'];

        end

        % Clicked callback: IntegrationTypeDropDown
        function IntegrationTypeDropDownClicked(app, event)
            if strcmp(app.IntegrationTypeDropDown.Value,'Exact LJ')
                app.Panel_22.Visible='on';
                app.Panel_11.Visible='off'; % Set the visibility of the panel to 'off'
            elseif strcmp(app.IntegrationTypeDropDown.Value,'Trapezoidal LJ') | ...
                    strcmp(app.IntegrationTypeDropDown.Value,'Numerical')
                app.Panel_11.Visible='on';
                app.Panel_22.Visible='off';
            else
                app.Panel_11.Visible='off';
                app.Panel_22.Visible='off';
            end
        end

        % Button pushed function: SelectDiffusionCSDataButton
        function SelectDiffusionCSDataButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_4.Value = fullfile(folder, baseName);
        end

        % Button pushed function: SelectFolderButton_3
        function SelectFolderButton_3Pushed(app, event)
            app.SelectedFolderEditField_3.Value = uigetdir;
        end

        % Button pushed function: SelectTransportInputFileButton_2
        function SelectTransportInputFileButton_2Pushed(app, event)
            [baseName, folder] = uigetfile();
            app.SelectedFileEditField_3.Value = fullfile(folder, baseName);
        end

        % Button pushed function: WriteTransportInputFileButton_2
        function WriteTransportInputFileButton_2Pushed(app, event)
            minT = app.minTKEditField.Value;
            maxT = app.maxTKEditField.Value;
            Tstep = app.TstepKEditField.Value;
            welldepth = app.welldepthKEditField.Value;
            m1 = app.m1amuEditField.Value;
            m2 = app.m2amuEditField.Value;
            %P = app.PressurebarEditField.Value;
            d = app.dangstromEditField.Value;
            diffusiondatafile = app.SelectedFileEditField_4.Value;
            viscositydatafile = app.SelectedFileEditField_7.Value;
            %coeftype = app.TransportCoefficientDropDown.Value;
            inttype = app.IntegrationTypeDropDown.Value;
            filename  = [app.SelectedFolderEditField_3.Value '/transportcoefinputfile.m'];
            inputfile=fopen(filename,'w');
            %fprintf(inputfile, 'coeftype = "%s";\n',coeftype);
            fprintf(inputfile, 'inttype = "%s";\n',inttype);
            fprintf(inputfile, 'minT = %f;\n',minT);
            fprintf(inputfile, 'maxT = %f;\n',maxT);
            fprintf(inputfile, 'Tstep = %f;\n', Tstep);
            fprintf(inputfile,'m1 = %f; \n',m1);
            fprintf(inputfile,'m2 = %f; \n',m2);
            
            % if strcmp(app.TransportCoefficientDropDown.Value,"Self-Diffusion")
            %     fprintf(inputfile,'p = %f; \n',P);
            % end
            if strcmp(app.IntegrationTypeDropDown.Value,"Trapezoidal LJ")
                fprintf(inputfile,'diffusiondatafile = "%s"; \n',diffusiondatafile);
                fprintf(inputfile,'viscositydatafile = "%s"; \n',viscositydatafile);
                fprintf(inputfile,'welldepth = %f; \n',welldepth);
                fprintf(inputfile,'d = %f; \n',d);
            elseif strcmp(app.IntegrationTypeDropDown.Value,'Exact LJ')
                fprintf(inputfile,'diffusiondatafile = "%s"; \n','dummyfile');
                fprintf(inputfile,'viscositydatafile = "%s"; \n','dummyfile');
                fprintf(inputfile,'welldepth = %f; \n',welldepth);
                fprintf(inputfile,'d = %f; \n',d);
            elseif strcmp(app.IntegrationTypeDropDown.Value,'Numerical')
                fprintf(inputfile,'diffusiondatafile = "%s"; \n',diffusiondatafile);
                fprintf(inputfile,'viscositydatafile = "%s"; \n',viscositydatafile);
            end
            fclose(inputfile);
            app.SelectedFileEditField_3.Value = filename;
        end

        % Button pushed function: ExecuteButton_5
        function ExecuteButton_5Pushed(app, event)
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            y = run_transportcoefs(app.SelectedFileEditField_3.Value,app.SelectedFolderEditField_3.Value);
            run(app.SelectedFileEditField_3.Value);
            diffusioncoefpath = fullfile(y,'/diffusioncoefdata.csv');
            viscositycoefpath = fullfile(y,'/viscositycoefdata.csv');
            tdif = readmatrix(diffusioncoefpath);
            tvisc = readmatrix(viscositycoefpath);
            tvals1 = tdif(:,1);
            tvals2 = tvisc(:,1);
            difcoefvals = tdif(:,2);
            visccoefvals = tvisc(:,2);
            plot(app.UIAxes_10,tvals1,difcoefvals, '-o','LineWidth', 1.8, 'MarkerSize', 4);
            plot(app.UIAxes_16,tvals2,visccoefvals, '-o','LineWidth', 1.8, 'MarkerSize', 4);
            
            title(app.UIAxes_10,'Pressure * Self-Diffusion Coefficient')
            ylabel(app.UIAxes_10,'P*D (Pa m^2/s)')
            title(app.UIAxes_16,'Viscosity Coefficient')
            ylabel(app.UIAxes_16,'\eta (\mu Pa s)')

            % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField_3.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes_10, fullfile(outputDir, 'DiffusionCoefficientPlot.png'));
            exportgraphics(app.UIAxes_16, fullfile(outputDir, 'ViscosityCoefficientPlot.png'));
            
            app.SelectedFolderEditField_2.Value = app.SelectedFolderEditField_3.Value;

            app.m1amuEditField_3.Value = app.m1amuEditField.Value;
            app.m2amuEditField_3.Value = app.m2amuEditField.Value;

            app.SelectedFileEditField_5.Value = viscositycoefpath;
            app.SelectedFileEditField_10.Value = diffusioncoefpath;
        end

        % Callback function
        function SelectedActionDropDownClicked(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential') 
                app.Panel_6.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_13.Visible='off';
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential') 
                app.Panel_13.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_6.Visible='off';
            else 
                app.Panel_6.Visible='off';
                app.Panel_13.Visible='off';
            end
            
        end

        % Callback function
        function SelectPotentialFileButton_2Pushed(app, event)
            %allows you to select input file from directory
            [baseName, folder] = uigetfile('*.csv');
            selpath19  = fullfile(folder, baseName);
            app.SelectedPathEditField_14.Value = selpath19;
        end

        % Clicked callback: IntegralTypeDropDown
        function IntegralTypeDropDownClicked(app, event)
            if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb') 
                app.Panel_17.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_16.Visible='off';
            elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical') 
                app.Panel_16.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_17.Visible='off';
            else 
                app.Panel_17.Visible='off';
                app.Panel_16.Visible='off';
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            app.SelectedPathEditField_15.Value = fullfile(folder, baseName);

        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Value changed function: minRminAEditField
        function minRminAEditFieldValueChanged(app, event)
            value = app.minRminAEditField.Value;
            if value == 0
               disp('Change your min Rmin value. 0 will break the root finder.')
            end
        end

        % Button pushed function: SelectPathButton_7
        function SelectPathButton_7Pushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedPathEditField_16.Value = fullfile(folder, baseName);
            
        end

        % Clicked callback: IntegralTypeDropDown_2
        function IntegralTypeDropDown_2Clicked(app, event)
            if strcmp(app.IntegralTypeDropDown_2.Value,'Exact LJ') 
                app.Panel_20.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_21.Visible='off';
            elseif strcmp(app.IntegralTypeDropDown_2.Value,'Numerical') 
                app.Panel_21.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_20.Visible='off';
            else 
                app.Panel_21.Visible='off';
                app.Panel_20.Visible='off';
            end
        end

        % Button pushed function: SelectViscosityCoefDataButton
        function SelectViscosityCoefDataButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_5.Value = fullfile(folder, baseName);
        end

        % Button pushed function: SelectFolderButton_2
        function SelectFolderButton_2Pushed(app, event)
            app.SelectedFolderEditField_2.Value = uigetdir;
        end

        % Button pushed function: WriteVHSInputFileButton
        function WriteVHSInputFileButtonPushed(app, event)
            N = app.NEditField.Value;
            tol = app.tolEditField.Value;
            m1 = app.m1amuEditField_3.Value;
            m2 = app.m2amuEditField_3.Value;
            %P = app.PressurebarEditField_2.Value;
            viscdatafile = app.SelectedFileEditField_5.Value;
            %difdatafile = app.SelectedFileEditField_7.Value;
            filename  = [app.SelectedFolderEditField_2.Value '/VHScoefinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile, 'N = %i;\n',N);
            fprintf(inputfile, 'tol = %e;\n',tol);
            fprintf(inputfile,'m1 = %f;\n',m1);
            fprintf(inputfile,'m2 = %f;\n',m2);
            %fprintf(inputfile,'P = %f;\n',P);
            fprintf(inputfile,'viscdatafile = "%s"; \n',viscdatafile);
            %fprintf(inputfile,'difdatafile = "%s"; \n',difdatafile);
            fclose(inputfile);
            app.SelectedFileEditField_6.Value = filename;
        end

        % Button pushed function: SelectVHSCoefInputFileButton
        function SelectVHSCoefInputFileButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            app.SelectedFileEditField_6.Value = fullfile(folder, baseName);
        end

        % Button pushed function: ExecuteButton_6
        function ExecuteButton_6Pushed(app, event)
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            run_vhscoef(app.SelectedFileEditField_6.Value,app.SelectedFolderEditField_2.Value)
            run(app.SelectedFileEditField_6.Value)
            hold(app.UIAxes_12,"on");
            cla(app.UIAxes_12);
            %hold(app.UIAxes_14,"on");
            %cla(app.UIAxes_14);
            axlegend = {'User Data'};
            interpviscdatapath = [app.SelectedFolderEditField_2.Value '/VHSfitdata/VHSfitviscdata.csv'];
            interpdifdatapath = [app.SelectedFolderEditField_2.Value '/VHSfitdata/VHSfitdifdata.csv'];
            interpviscdata = readmatrix(interpviscdatapath);
            %interpdifdata = readmatrix(interpdifdatapath);
            plot(app.UIAxes_12,interpviscdata(:,1),interpviscdata(:,2))
            %plot(app.UIAxes_14,interpdifdata(:,1),interpdifdata(:,2))
            rownames = {};
            for i = 1:N
                axlegend  = [axlegend sprintf('n=%i',i)];
                dsmcviscdatapath  = [app.SelectedFolderEditField_2.Value sprintf('/VHSfitdata/viscfitdata_n=%i.csv',i)];
                dsmcviscdata = readmatrix(dsmcviscdatapath);
                %dsmcdifdatapath  = [app.SelectedFolderEditField_2.Value sprintf('/dsmcfitdata/diffitdata_n=%i.csv',i)];
                %dsmcdifdata = readmatrix(dsmcdifdatapath);
                tvals1 = dsmcviscdata(:,1);
                muvals = dsmcviscdata(:,2);
                %tvals2 = dsmcdifdata(:,1);
                %Dvals = dsmcdifdata(:,2);
                plot(app.UIAxes_12,tvals1,muvals,'-o');
                %plot(app.UIAxes_14,tvals2,Dvals,'-o');
                %yscale(app.UIAxes_12,'log');
                %xscale(app.UIAxes_12,'log');
                rownames{i} = sprintf('n = %i', i);
            end
            disp(rownames)
            coefpath = [app.SelectedFolderEditField_2.Value '/VHScoeftable.csv'];
            coeftable = readmatrix(coefpath);
            app.UITable.Data = coeftable;
            app.UITable.ColumnName = {'Tmin (K)','Tmax (K)','mu_ref (mu_Pa s)',...
                'T_ref (K)','omega','collision_diam (A)'};
            app.UITable.RowName = rownames;

            legend(app.UIAxes_12,axlegend);
            %legend(app.UIAxes_14,axlegend);

            app.SelectedFolderEditField_11.Value = app.SelectedFolderEditField_2.Value;

            % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField_2.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes_12, fullfile(outputDir, 'FittedViscosityCoefficientPlot.png'));
            app.m1amuEditField_4.Value = app.m1amuEditField_3.Value;
            app.m2amuEditField_4.Value = app.m2amuEditField_3.Value;
            app.SelectedFileEditField_11.Value = coefpath;
     
        end

        % Value changed function: LogspacingCheckBox
        function LogspacingCheckBoxValueChanged(app, event)
            value = app.LogspacingCheckBox.Value;
            if value == 1
                app.Panel_24.Visible = 'on';
                app.Panel_23.Visible = 'off';
            elseif value == 0
                app.Panel_24.Visible = 'off';
                app.Panel_23.Visible = 'on';
            end
        end

        % Value changed function: LogspacingCheckBox_2
        function LogspacingCheckBox_2ValueChanged(app, event)
            value = app.LogspacingCheckBox_2.Value;
            if value == 1
                app.Panel_25.Visible = 'on';
                app.Panel_26.Visible = 'off';
            elseif value == 0
                app.Panel_25.Visible = 'off';
                app.Panel_26.Visible = 'on';
            end
        end

        % Callback function
        function SelectFolderButton_6Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_6.Value = app.rgtfunpath;
        end

        % Callback function
        function ConfirmButtonPushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';

        end

        % Callback function
        function SelectLocationofRGTFunFolderButtonPushed(app, event)
            app.Panel_27.Visible = 'on';
            
        end

        % Button pushed function: ConfirmButton_3
        function ConfirmButton_3Pushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';

        end

        % Button pushed function: ConfirmButton_4
        function ConfirmButton_4Pushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';

        end

        % Button pushed function: ConfirmButton_5
        function ConfirmButton_5Pushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';

        end

        % Button pushed function: ConfirmButton_6
        function ConfirmButton_6Pushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';

        end

        % Button pushed function: SelectLocationofRGTFunFolderButton_2
        function SelectLocationofRGTFunFolderButton_2Pushed(app, event)
            app.Panel_28.Visible = 'on';
        end

        % Button pushed function: SelectLocationofRGTFunFolderButton_3
        function SelectLocationofRGTFunFolderButton_3Pushed(app, event)
            app.Panel_29.Visible = 'on';
        end

        % Button pushed function: SelectLocationofRGTFunFolderButton_4
        function SelectLocationofRGTFunFolderButton_4Pushed(app, event)
            app.Panel_30.Visible = 'on';
        end

        % Button pushed function: SelectLocationofRGTFunFolderButton_5
        function SelectLocationofRGTFunFolderButton_5Pushed(app, event)
            app.Panel_31.Visible = 'on';
        end

        % Button pushed function: SelectFolderButton_7
        function SelectFolderButton_7Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_7.Value = app.rgtfunpath;
        end

        % Button pushed function: SelectFolderButton_8
        function SelectFolderButton_8Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_8.Value = app.rgtfunpath;
        end

        % Button pushed function: SelectFolderButton_9
        function SelectFolderButton_9Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_9.Value = app.rgtfunpath;
        end

        % Button pushed function: SelectFolderButton_10
        function SelectFolderButton_10Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_10.Value = app.rgtfunpath;
        end

        % Callback function
        function ImageClicked(app, event)
            app.Panel_32.Visible = 'on';
        end

        % Button pushed function: CloseButton_6
        function CloseButton_6Pushed(app, event)
            app.Panel_32.Visible = 'off';
        end

        % Callback function
        function Image_2Clicked(app, event)
            app.Panel_33.Visible = 'on';
        end

        % Callback function
        function CloseButton_7Pushed(app, event)
            app.Panel_33.Visible = 'off';
        end

        % Callback function
        function Image_3Clicked(app, event)
            app.Panel_34.Visible = 'on';
        end

        % Button pushed function: CloseButton_8
        function CloseButton_8Pushed(app, event)
            app.Panel_34.Visible = 'off';
        end

        % Callback function
        function Image_4Clicked(app, event)
            app.Panel_35.Visible = 'on';
        end

        % Button pushed function: CloseButton_9
        function CloseButton_9Pushed(app, event)
            app.Panel_35.Visible = 'off';
        end

        % Callback function
        function Image_5Clicked(app, event)
            app.Panel_36.Visible = 'on';
        end

        % Button pushed function: CloseButton_10
        function CloseButton_10Pushed(app, event)
            app.Panel_36.Visible = 'off';
        end

        % Button pushed function: SelectViscosityCSDataButton
        function SelectViscosityCSDataButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_7.Value = fullfile(folder, baseName);

        end

        % Callback function
        function Image2Clicked(app, event)
            app.Panel_33.Visible = 'on';
        end

        % Image clicked function: Image2_2
        function Image2_2Clicked(app, event)
            app.Panel_32.Visible = 'on';
        end

        % Image clicked function: Image2_3
        function Image2_3Clicked(app, event)
            app.Panel_34.Visible = 'on';
        end

        % Image clicked function: Image2_4
        function Image2_4Clicked(app, event)
            app.Panel_35.Visible = 'on';
        end

        % Image clicked function: Image2_5
        function Image2_5Clicked(app, event)
            app.Panel_36.Visible = 'on';
        end

        % Value changed function: LogspacingCheckBox_3
        function LogspacingCheckBox_3ValueChanged(app, event)
            value = app.LogspacingCheckBox_3.Value;
            if value == 1
                app.Panel_37.Visible = 'on';
                app.Panel_38.Visible = 'off';
            elseif value == 0
                app.Panel_37.Visible = 'off';
                app.Panel_38.Visible = 'on';
            end
        end

        % Clicked callback: TotalCrossSectionCutoffDropDown
        function TotalCrossSectionCutoffDropDownClicked(app, event)
            if strcmp(app.TotalCrossSectionCutoffDropDown.Value,'Quantum') 
                app.Panel_41.Visible='off'; % Set the visibility of the panel to 'on'
                app.Panel_42.Visible='on';
            elseif strcmp(app.TotalCrossSectionCutoffDropDown.Value,'Manual') 
                app.Panel_41.Visible = 'on';
                app.Panel_42.Visible='off';
            end
               
        end

        % Button pushed function: SelectDOCAfileButton
        function SelectDOCAfileButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedPathEditField_17.Value = fullfile(folder, baseName);
        end

        % Callback function
        function SelectViscosityCoefDataButton_2Pushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_8.Value = fullfile(folder, baseName);
        end

        % Button pushed function: SelectLocationofRGTFunFolderButton_6
        function SelectLocationofRGTFunFolderButton_6Pushed(app, event)
            app.Panel_43.Visible = 'on';
        end

        % Button pushed function: SelectFolderButton_11
        function SelectFolderButton_11Pushed(app, event)
            app.SelectedFolderEditField_11.Value = uigetdir;
        end

        % Button pushed function: SelectDiffusionCoefDataButton
        function SelectDiffusionCoefDataButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_10.Value = fullfile(folder, baseName);
        end

        % Button pushed function: SelectVHSCoefTableButton
        function SelectVHSCoefTableButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.SelectedFileEditField_11.Value = fullfile(folder, baseName);
        end

        % Button pushed function: SelectVSSCoefInputFileButton
        function SelectVSSCoefInputFileButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            app.SelectedFileEditField_9.Value = fullfile(folder, baseName);
        end

        % Image clicked function: Image2_6
        function Image2_6Clicked(app, event)
            app.Panel_44.Visible = 'on';
        end

        % Button pushed function: SelectFolderButton_12
        function SelectFolderButton_12Pushed(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_12.Value = app.rgtfunpath;
        end

        % Button pushed function: WriteVSSInputFileButton
        function WriteVSSInputFileButtonPushed(app, event)
            alphaguess = app.alphaguessEditField.Value;
            tol = app.leastsquarestolEditField.Value;
            m1 = app.m1amuEditField_4.Value;
            m2 = app.m2amuEditField_4.Value;
            diffusiondatafile = app.SelectedFileEditField_10.Value;
            vhstablefile = app.SelectedFileEditField_11.Value;
            filename  = [app.SelectedFolderEditField_11.Value '/VSScoefinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile, 'alphaguess = %i;\n',alphaguess);
            fprintf(inputfile, 'tol = %e;\n',tol);
            fprintf(inputfile,'m1 = %f;\n',m1);
            fprintf(inputfile,'m2 = %f;\n',m2);
            fprintf(inputfile,'diffusiondatafile = "%s"; \n',diffusiondatafile);
            fprintf(inputfile,'vhstablefile = "%s"; \n',vhstablefile);
            fclose(inputfile);
            app.SelectedFileEditField_9.Value = filename;
        end

        % Button pushed function: ConfirmButton_7
        function ConfirmButton_7Pushed(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';
        end

        % Button pushed function: CloseButton_12
        function CloseButton_12Pushed(app, event)
            app.Panel_44.Visible = 'off';
        end

        % Button pushed function: ExecuteButton_7
        function ExecuteButton_7Pushed(app, event)
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            run_vsscoef(app.SelectedFileEditField_9.Value,app.SelectedFolderEditField_11.Value)
            run(app.SelectedFileEditField_9.Value)
            vsscoefpath = [app.SelectedFolderEditField_11.Value '/VSScoeftable.csv'];
            vsscoeftable = readmatrix(vsscoefpath);
            app.UITable_2.Data = vsscoeftable;
            app.UITable_2.ColumnName = {'Reference Temp (K)','alpha','collision_diam (A)'};

        end

        % Button pushed function: SelectLocationofChebfunFolderButton
        function SelectLocationofChebfunFolderButtonPushed(app, event)
            app.Panel_45.Visible = 'on';
        end

        % Button pushed function: SelectFolderButton_13
        function SelectFolderButton_13Pushed(app, event)
            chebfunpath = uigetdir;
            app.SelectedFolderEditField_13.Value = chebfunpath;
        end

        % Button pushed function: ConfirmButton_2
        function ConfirmButton_2Pushed(app, event)
            app.Panel_45.Visible = 'off';
        end

        % Clicked callback: SelectedActionDropDown
        function SelectedActionDropDownClicked2(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential') 
                app.Panel_6.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_13.Visible='off';
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential') 
                app.Panel_13.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_6.Visible='off';
            else 
                app.Panel_6.Visible='off';
                app.Panel_13.Visible='off';
            end
            
        end

        % Clicked callback: FitPotentialTypeDropDown
        function FitPotentialTypeDropDownClicked2(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'12-6 Lennard-Jones') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'12-4 Lennard-Jones')
                    app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Morse')
                    app.Panel_40.Visible='on'; 
                    app.Panel_2.Visible='off'; 
                    app.Panel.Visible='off';
                    app.Panel_18.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_18.Visible = 'on';
                    app.Panel.Visible = 'off';
                    app.Panel_2.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                else 
                    app.Panel.Visible='off';
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                    app.Panel_40.Visible='off'; 
                end
            
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel_14.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'12-6 Lennard-Jones') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'12-4 Lennard-Jones')
                    app.Panel_15.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_14.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Morse')
                    app.Panel_39.Visible='on'; 
                    app.Panel_19.Visible = 'off';
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_19.Visible = 'on';
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                    app.Panel_39.Visible='off'; 
                else 
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                    app.Panel_39.Visible='off'; 
                end
            end


        end

        % Button pushed function: SelectLocationofRGTFunFolderButton
        function SelectLocationofRGTFunFolderButtonPushed2(app, event)
            app.Panel_27.Visible = 'on';
        end

        % Callback function
        function SelectFolderButton_6Pushed2(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_6.Value = app.rgtfunpath;
        end

        % Callback function
        function ConfirmButtonPushed2(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';
        end

        % Button pushed function: CloseButton_7
        function CloseButton_7Pushed2(app, event)
            app.Panel_33.Visible = 'off';
        end

        % Button pushed function: SelectPotentialFileButton_2
        function SelectPotentialFileButton_2Pushed2(app, event)
            %allows you to select input file from directory
            [baseName, folder] = uigetfile('*.csv');
            selpath19  = fullfile(folder, baseName);
            app.SelectedPathEditField_14.Value = selpath19;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed2(app, event)
            app.SelectedFolderEditField.Value = uigetdir;
        end

        % Button pushed function: WriteInputFileButton
        function WriteInputFileButtonPushed3(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                minr = app.minrAEditField_2.Value;
                maxr = app.maxrAEditField_2.Value;
                rstep = app.rstepAEditField.Value;
                z1 = app.Z1EditField.Value;
                z2 = app.Z2EditField.Value;
                a = app.aEditField.Value;
                k = app.kEditField.Value;
                well_eps = app.WellDeptheVEditField.Value;
                sig = app.SigmaAEditField.Value;
                filename  = [app.SelectedFolderEditField.Value '/potentialinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile, 'Rstep = %f;\n',rstep);
                if strcmp(app.FitPotentialTypeDropDown.Value,"Coulomb") | ...
                    strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'Z2 = %i;\n',z2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(inputfile, 'eps_well = %f;\n',well_eps);
                    fprintf(inputfile, 'sig = %i;\n',sig);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    eps_well = app.welldeptheVEditField_2.Value;
                    k = app.keVA2EditField.Value;
                    rm = app.rmAEditField.Value;
                    fprintf(inputfile, 'eps_well = %f;\n',eps_well);
                    fprintf(inputfile, 'k = %f;\n',k);
                    fprintf(inputfile, 'rm = %f;\n',rm);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'a = %f;\n',a);
                    fprintf(inputfile, 'k = %f;\n',k);
                end
                fclose(inputfile);

                outputfilename  = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
                outputfile=fopen(outputfilename,'w');
                fprintf(outputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(outputfile, 'minR = %f;\n',minr);
                fprintf(outputfile, 'maxR = %f;\n',maxr);
                if strcmp(app.FitPotentialTypeDropDown.Value, "Coulomb") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(outputfile, 'Z1 = %i;\n',z1);
                    fprintf(outputfile,'z2_param = %i;\n',z2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(outputfile,'eps_param = %f;\n',well_eps);
                    fprintf(outputfile,'sigma_param = %f;\n',sig);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    eps_well = app.welldeptheVEditField_2.Value;
                    k = app.keVA2EditField.Value;
                    rm = app.rmAEditField.Value;
                    fprintf(outputfile,'eps_param = %f;\n',eps_well);
                    fprintf(outputfile,'k_param = %f;\n',k);
                    fprintf(outputfile,'rm_param = %f;\n',rm);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(outputfile,'a_param = %f;\n',a);
                    fprintf(outputfile,'k_param = %f;\n',k);
                end
                fclose(outputfile);
                
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                z1 = app.Z1EditField_3.Value;
                minr = app.minrAEditField.Value;
                maxr = app.maxrAEditField.Value;
                tol = app.toleranceEditField_2.Value;
                minz2 = app.minZ2EditField_2.Value;
                maxz2 = app.maxZ2EditField_2.Value;
                min_eps = app.minwelldeptheVEditField.Value;
                max_eps = app.maxwelldeptheVEditField.Value;
                min_sigma = app.minsigmaAEditField.Value;
                max_sigma = app.maxsigmaAEditField.Value;
                eps_start = app.welldepthguesseVEditField.Value;
                sigma_start = app.sigmaguessAEditField.Value;
                k_start = app.kguessEditField.Value;
                a_start = app.aguessEditField.Value;
                maxa = app.maxaEditField.Value;
                mina = app.minaEditField.Value;
                maxk = app.maxkEditField.Value;
                mink = app.minkEditField.Value;
                filename  = [app.SelectedFolderEditField.Value '/fitinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile,'tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, 'Coulomb') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'minZ2 = %i;\n',minz2);
                    fprintf(inputfile, 'maxZ2 = %i;\n',maxz2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(inputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(inputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(inputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(inputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    min_eps = app.minepseVEditField.Value;
                    max_eps = app.maxepseVEditField.Value;
                    eps_guess = app.epsguesseVEditField.Value;
                    min_k = app.minkEditField_2.Value;
                    max_k = app.maxkEditField_2.Value;
                    k_guess = app.kguessEditField_2.Value;
                    min_rm = app.minrmAEditField.Value;
                    max_rm = app.maxrmAEditField.Value;
                    rm_guess = app.rmguessAEditField.Value;
                    fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(inputfile, 'min_k = %f;\n',min_k);
                    fprintf(inputfile, 'max_k = %f;\n',max_k);
                    fprintf(inputfile, 'min_rm = %f;\n',min_rm);
                    fprintf(inputfile, 'max_rm = %f;\n',max_rm);
                    fprintf(inputfile, 'eps_start = %f;\n',eps_guess);
                    fprintf(inputfile, 'k_start = %f;\n',k_guess);
                    fprintf(inputfile, 'rm_start = %f;\n',rm_guess);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'min_a = %f;\n',mina);
                    fprintf(inputfile, 'max_a = %f;\n',maxa);
                    fprintf(inputfile, 'min_k = %f;\n',mink);
                    fprintf(inputfile, 'max_k = %f;\n',maxk);
                    fprintf(inputfile, 'k_start = %f;\n', k_start);
                    fprintf(inputfile, 'a_start = %f;\n', a_start);
                end
                fclose(inputfile);
            end
         app.SelectedPathEditField_4.Value = filename;
        end

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed3(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.SelectedPathEditField_4.Value = fullfile(folder, baseName);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed2(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            srcpath = [app.rgtfunpath '/src'];
            addpath(srcpath)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                y = run_calcpotential(app.SelectedPathEditField_4.Value,...
                app.SelectedFolderEditField.Value);
                t = readmatrix(y);
                xvals = t(:,1);
                yvals = t(:,2);
                plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
                plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
                yscale(app.UIAxes_2,'log');
                xscale(app.UIAxes_2,'linear');
                title(app.UIAxes,'Interatomic Potential');
                title(app.UIAxes_2,'Interatomic Potential');
                ylim(app.UIAxes,[-10,10])
                %app.SelectedPathEditField.Value = y;
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                run(app.SelectedPathEditField_4.Value);
                datalocation = app.SelectedPathEditField_14.Value;
                t = readmatrix(datalocation);
                myfit = run_fitpotential(app.SelectedPathEditField_4.Value,...
                    app.SelectedPathEditField_14.Value);
                coefs = coeffvalues(myfit);
                disp(coefs(1))
                disp(coefs(2))
                outputfilename  = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
                outputfile=fopen(outputfilename,'w');
                fprintf(outputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(outputfile, 'minR = %f;\n',minR);
                fprintf(outputfile, 'maxR = %f;\n',maxR);
                fprintf(outputfile, 'Tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, "Coulomb") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(outputfile, 'Z1 = %i;\n',Z1);
                    fprintf(outputfile,'z2_param = %f;\n',coefs);
                    fprintf(outputfile, 'minZ2 = %i;\n',minZ2);
                    fprintf(outputfile, 'maxZ2 = %i;\n',maxZ2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"12-6 Lennard-Jones") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,"12-4 Lennard-Jones")
                    fprintf(outputfile,'eps_param = %f;\n',coefs(1));
                    fprintf(outputfile,'sigma_param = %f;\n',coefs(2));
                    fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(outputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(outputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(outputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Morse")
                    fprintf(outputfile,'rm_param = %f;\n',coefs(1));
                    fprintf(outputfile,'eps_param = %f;\n',coefs(2));
                    fprintf(outputfile,'k_param = %f;\n',coefs(3));
                    fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(outputfile, 'min_k = %f;\n',min_k);
                    fprintf(outputfile, 'max_k = %f;\n',max_k);
                    fprintf(outputfile, 'min_rm = %f;\n',min_rm);
                    fprintf(outputfile, 'max_rm = %f;\n',max_rm);
                    fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(outputfile, 'k_start = %f;\n',k_start);
                    fprintf(outputfile, 'rm_start = %f;\n',rm_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(outputfile,'a_param = %f;\n',coefs(1));
                    fprintf(outputfile,'k_param = %f;\n',coefs(2));
                    fprintf(outputfile, 'min_a = %f;\n',min_a);
                    fprintf(outputfile, 'max_a = %f;\n',max_a);
                    fprintf(outputfile, 'min_k = %f;\n',min_k);
                    fprintf(outputfile, 'max_k = %f;\n',max_k);
                    fprintf(outputfile, 'a_start = %f;\n',a_start);
                    fprintf(outputfile, 'k_start = %f;\n',k_start);
                end
                fclose(outputfile);
                excludex = ((t(:,1) < minR) | (t(:,1) > maxR));
                xvals = t(:,1);
                yvals = t(:,2);
                xvals(excludex) = [];
                yvals(excludex) = [];
               
                plot(app.UIAxes,myfit,xvals,yvals);
                title(app.UIAxes,'Fitted Interatomic Potential');
                % plot(app.UIAxes_4,myfit,xvals,yvals);
                % yscale(app.UIAxes_4,'log');
                % xscale(app.UIAxes_4,'linear');
            end
            
            % Save the plots as images
            outputDir = fullfile(app.SelectedFolderEditField.Value,'/images'); % Specify your desired output directory
            if ~exist(outputDir, 'dir')
                mkdir(outputDir); % Create the directory if it doesn't exist
            end
            
            % Save each axes as a PNG
            exportgraphics(app.UIAxes, fullfile(outputDir, 'PotentialPlot.png'));
            app.SelectedPathEditField_15.Value = [app.SelectedFolderEditField.Value '/fitoutputfile.m'];
            app.SelectedFolderEditField_5.Value = app.SelectedFolderEditField.Value;
      

        end

        % Image clicked function: Image2
        function Image2Clicked2(app, event)
            app.Panel_33.Visible = 'on';
        end

        % Button pushed function: SelectFolderButton_6
        function SelectFolderButton_6Pushed3(app, event)
            app.rgtfunpath = uigetdir;
            app.SelectedFolderEditField_6.Value = app.rgtfunpath;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed3(app, event)
            app.Panel_27.Visible = 'off';
            app.Panel_28.Visible = 'off';
            app.Panel_29.Visible = 'off';
            app.Panel_30.Visible = 'off';
            app.Panel_31.Visible = 'off';
            app.Panel_43.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PotentialTab = uitab(app.TabGroup);
            app.PotentialTab.Title = 'Potential';
            app.GridLayout = uigridlayout(app.PotentialTab);
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.WriteInputFileButton = uibutton(app.GridLayout, 'push');
            app.WriteInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteInputFileButtonPushed3, true);
            app.WriteInputFileButton.Text = 'Write Input File';
            app.ExecuteButton = uibutton(app.GridLayout, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed2, true);
            app.ExecuteButton.Text = 'Execute';
            app.SelectFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed2, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel = uilabel(app.GridLayout);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel.Text = 'Specify folder to save input/output files and data for this study';
            app.OrSelectYourOwnInputFileLabel = uilabel(app.GridLayout);
            app.OrSelectYourOwnInputFileLabel.Text = 'Or Select Your Own Input File';
            app.SelectInputFileButton = uibutton(app.GridLayout, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed3, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.Panel_12 = uipanel(app.GridLayout);
            app.Panel_6 = uipanel(app.Panel_12);
            app.maxrAEditField_2Label = uilabel(app.Panel_6);
            app.maxrAEditField_2Label.Text = 'max r (A)';
            app.maxrAEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.minrAEditField_2Label = uilabel(app.Panel_6);
            app.minrAEditField_2Label.Text = 'min r (A) ';
            app.minrAEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.Panel_2 = uipanel(app.Panel_6);
            app.WellDeptheVEditFieldLabel = uilabel(app.Panel_2);
            app.WellDeptheVEditFieldLabel.Text = 'Well Depth (eV)';
            app.WellDeptheVEditField = uieditfield(app.Panel_2, 'numeric');
            app.SigmaALabel = uilabel(app.Panel_2);
            app.SigmaALabel.Text = 'Sigma (A)';
            app.SigmaAEditField = uieditfield(app.Panel_2, 'numeric');
            app.rstepAEditFieldLabel = uilabel(app.Panel_6);
            app.rstepAEditFieldLabel.Text = 'r step (A)';
            app.rstepAEditField = uieditfield(app.Panel_6, 'numeric');
            app.Panel_18 = uipanel(app.Panel_6);
            app.aEditFieldLabel = uilabel(app.Panel_18);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_18, 'numeric');
            app.kAngstrom1Label = uilabel(app.Panel_18);
            app.kAngstrom1Label.Text = 'k ';
            app.kEditField = uieditfield(app.Panel_18, 'numeric');
            app.Panel = uipanel(app.Panel_6);
            app.Z1EditFieldLabel = uilabel(app.Panel);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.Panel, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.Panel);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.Panel, 'numeric');
            app.Panel_40 = uipanel(app.Panel_6);
            app.welldeptheVEditField_2Label = uilabel(app.Panel_40);
            app.welldeptheVEditField_2Label.Text = 'well depth (eV)';
            app.welldeptheVEditField_2 = uieditfield(app.Panel_40, 'numeric');
            app.keVA2EditFieldLabel = uilabel(app.Panel_40);
            app.keVA2EditFieldLabel.Text = 'k (eV / A^2)';
            app.keVA2EditField = uieditfield(app.Panel_40, 'numeric');
            app.rmAEditFieldLabel = uilabel(app.Panel_40);
            app.rmAEditFieldLabel.Text = 'rm (A)';
            app.rmAEditField = uieditfield(app.Panel_40, 'numeric');
            app.Panel_13 = uipanel(app.Panel_12);
            app.toleranceEditField_2Label = uilabel(app.Panel_13);
            app.toleranceEditField_2Label.Text = 'tolerance';
            app.toleranceEditField_2 = uieditfield(app.Panel_13, 'numeric');
            app.minrAEditFieldLabel = uilabel(app.Panel_13);
            app.minrAEditFieldLabel.Text = 'min r (A) ';
            app.minrAEditField = uieditfield(app.Panel_13, 'numeric');
            app.maxrALabel = uilabel(app.Panel_13);
            app.maxrALabel.Text = 'max r (A)';
            app.maxrAEditField = uieditfield(app.Panel_13, 'numeric');
            app.SelectPotentialFileButton_2 = uibutton(app.Panel_13, 'push');
            app.SelectPotentialFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialFileButton_2Pushed2, true);
            app.SelectPotentialFileButton_2.Text = 'Select Potential File';
            app.SelectedPathEditField_14Label = uilabel(app.Panel_13);
            app.SelectedPathEditField_14Label.Text = 'Selected Path';
            app.SelectedPathEditField_14 = uieditfield(app.Panel_13, 'text');
            app.Panel_14 = uipanel(app.Panel_13);
            app.Panel_14.Visible = 'off';
            app.SpecifyCoulombZBLParametersLabel = uilabel(app.Panel_14);
            app.SpecifyCoulombZBLParametersLabel.Text = 'Specify Coulomb/ZBL Parameters';
            app.Z1EditField_3Label = uilabel(app.Panel_14);
            app.Z1EditField_3Label.Text = 'Z1';
            app.Z1EditField_3 = uieditfield(app.Panel_14, 'numeric');
            app.maxZ2EditField_2Label = uilabel(app.Panel_14);
            app.maxZ2EditField_2Label.Text = 'max Z2';
            app.maxZ2EditField_2 = uieditfield(app.Panel_14, 'numeric');
            app.minZ2EditField_2Label = uilabel(app.Panel_14);
            app.minZ2EditField_2Label.Text = 'min Z2';
            app.minZ2EditField_2 = uieditfield(app.Panel_14, 'numeric');
            app.Panel_15 = uipanel(app.Panel_13);
            app.SpecifyLJParametersLabel_3 = uilabel(app.Panel_15);
            app.SpecifyLJParametersLabel_3.Text = 'Specify LJ Parameters';
            app.minwelldeptheVEditFieldLabel = uilabel(app.Panel_15);
            app.minwelldeptheVEditFieldLabel.Text = 'min well depth (eV)';
            app.minwelldeptheVEditField = uieditfield(app.Panel_15, 'numeric');
            app.maxwelldeptheVEditFieldLabel = uilabel(app.Panel_15);
            app.maxwelldeptheVEditFieldLabel.Text = 'max well depth (eV)';
            app.maxwelldeptheVEditField = uieditfield(app.Panel_15, 'numeric');
            app.minsigmaAEditFieldLabel = uilabel(app.Panel_15);
            app.minsigmaAEditFieldLabel.Text = 'min sigma (A)';
            app.minsigmaAEditField = uieditfield(app.Panel_15, 'numeric');
            app.maxsigmaAEditFieldLabel = uilabel(app.Panel_15);
            app.maxsigmaAEditFieldLabel.Text = 'max sigma (A)';
            app.maxsigmaAEditField = uieditfield(app.Panel_15, 'numeric');
            app.welldepthguesseVEditFieldLabel = uilabel(app.Panel_15);
            app.welldepthguesseVEditFieldLabel.Text = 'well depth guess (eV)';
            app.welldepthguesseVEditField = uieditfield(app.Panel_15, 'numeric');
            app.sigmaguessALabel = uilabel(app.Panel_15);
            app.sigmaguessALabel.Text = 'sigma guess (A)';
            app.sigmaguessAEditField = uieditfield(app.Panel_15, 'numeric');
            app.Panel_19 = uipanel(app.Panel_13);
            app.SpecifyPowerLawParametersLabel = uilabel(app.Panel_19);
            app.SpecifyPowerLawParametersLabel.Text = 'Specify Power Law Parameters';
            app.minaEditFieldLabel = uilabel(app.Panel_19);
            app.minaEditFieldLabel.Text = 'min a';
            app.minaEditField = uieditfield(app.Panel_19, 'numeric');
            app.maxaEditFieldLabel = uilabel(app.Panel_19);
            app.maxaEditFieldLabel.Text = 'max a';
            app.maxaEditField = uieditfield(app.Panel_19, 'numeric');
            app.minkEditFieldLabel = uilabel(app.Panel_19);
            app.minkEditFieldLabel.Text = 'min k';
            app.minkEditField = uieditfield(app.Panel_19, 'numeric');
            app.maxkEditFieldLabel = uilabel(app.Panel_19);
            app.maxkEditFieldLabel.Text = 'max k';
            app.maxkEditField = uieditfield(app.Panel_19, 'numeric');
            app.aguessEditFieldLabel = uilabel(app.Panel_19);
            app.aguessEditFieldLabel.Text = 'a guess';
            app.aguessEditField = uieditfield(app.Panel_19, 'numeric');
            app.kguessEditFieldLabel = uilabel(app.Panel_19);
            app.kguessEditFieldLabel.Text = 'k guess';
            app.kguessEditField = uieditfield(app.Panel_19, 'numeric');
            app.Panel_39 = uipanel(app.Panel_13);
            app.SpecifyMorseParametersLabel = uilabel(app.Panel_39);
            app.SpecifyMorseParametersLabel.Text = 'Specify Morse Parameters';
            app.minepseVEditFieldLabel = uilabel(app.Panel_39);
            app.minepseVEditFieldLabel.Text = 'min eps (eV)';
            app.minepseVEditField = uieditfield(app.Panel_39, 'numeric');
            app.maxepseVEditFieldLabel = uilabel(app.Panel_39);
            app.maxepseVEditFieldLabel.Text = 'max eps (eV)';
            app.maxepseVEditField = uieditfield(app.Panel_39, 'numeric');
            app.epsguesseVEditFieldLabel = uilabel(app.Panel_39);
            app.epsguesseVEditFieldLabel.Text = 'eps guess (eV)';
            app.epsguesseVEditField = uieditfield(app.Panel_39, 'numeric');
            app.minkEditField_2Label = uilabel(app.Panel_39);
            app.minkEditField_2Label.Text = 'min k';
            app.minkEditField_2 = uieditfield(app.Panel_39, 'numeric');
            app.maxkEditField_2Label = uilabel(app.Panel_39);
            app.maxkEditField_2Label.Text = 'max k';
            app.maxkEditField_2 = uieditfield(app.Panel_39, 'numeric');
            app.kguessEditField_2Label = uilabel(app.Panel_39);
            app.kguessEditField_2Label.Text = 'k guess';
            app.kguessEditField_2 = uieditfield(app.Panel_39, 'numeric');
            app.minrmAEditFieldLabel = uilabel(app.Panel_39);
            app.minrmAEditFieldLabel.Text = 'min rm (A)';
            app.minrmAEditField = uieditfield(app.Panel_39, 'numeric');
            app.maxrmAEditFieldLabel = uilabel(app.Panel_39);
            app.maxrmAEditFieldLabel.Text = 'max rm (A)';
            app.maxrmAEditField = uieditfield(app.Panel_39, 'numeric');
            app.rmguessAEditFieldLabel = uilabel(app.Panel_39);
            app.rmguessAEditFieldLabel.Text = 'rm guess (A';
            app.rmguessAEditField = uieditfield(app.Panel_39, 'numeric');
            app.SelectLocationofRGTFunFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectLocationofRGTFunFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButtonPushed2, true);
            app.SelectLocationofRGTFunFolderButton.Text = 'Select Location of RGTFun Folder';
            app.Panel_46 = uipanel(app.GridLayout);
            app.CalculateFitPotentialLabel = uilabel(app.Panel_46);
            app.CalculateFitPotentialLabel.Text = 'Calculate/Fit Potential';
            app.CreatepotentialdataorfitanRGTFunsupportedpotentialtoyourdataLabel = uilabel(app.Panel_46);
            app.CreatepotentialdataorfitanRGTFunsupportedpotentialtoyourdataLabel.Text = 'Create potential data or fit an RGTFun-supported potential to your data.';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked2, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel_2 = uilabel(app.GridLayout);
            app.ClickTheGuideforhelpLabel_2.Text = 'Click The Guide for help!';
            app.Panel_27 = uipanel(app.GridLayout);
            app.PleaseselectlocationofRGTFunFolderLabel = uilabel(app.Panel_27);
            app.PleaseselectlocationofRGTFunFolderLabel.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_6 = uibutton(app.Panel_27, 'push');
            app.SelectFolderButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_6Pushed3, true);
            app.SelectFolderButton_6.Text = 'Select Folder ';
            app.SelectedFolderEditField_6Label = uilabel(app.Panel_27);
            app.SelectedFolderEditField_6Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_6 = uieditfield(app.Panel_27, 'text');
            app.ConfirmButton = uibutton(app.Panel_27, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed3, true);
            app.ConfirmButton.Text = 'Confirm';
            app.SelectedFolderEditFieldLabel = uilabel(app.GridLayout);
            app.SelectedFolderEditFieldLabel.Text = 'Selected Folder';
            app.SelectedFolderEditField = uieditfield(app.GridLayout, 'text');
            app.SelectedPathEditField_4Label = uilabel(app.GridLayout);
            app.SelectedPathEditField_4Label.Text = 'Selected Path';
            app.SelectedPathEditField_4 = uieditfield(app.GridLayout, 'text');
            app.FitPotentialTypeDropDownLabel = uilabel(app.GridLayout);
            app.FitPotentialTypeDropDownLabel.Text = 'Fit/Potential Type';
            app.FitPotentialTypeDropDown = uidropdown(app.GridLayout);
            app.FitPotentialTypeDropDown.Items = {'Select Potential', 'Coulomb', '12-6 Lennard-Jones', '12-4 Lennard-Jones', 'ZBL', 'Morse', 'Power Law'};
            app.FitPotentialTypeDropDown.ClickedFcn = createCallbackFcn(app, @FitPotentialTypeDropDownClicked2, true);
            app.FitPotentialTypeDropDown.Value = 'Select Potential';
            app.SelectedActionDropDownLabel = uilabel(app.GridLayout);
            app.SelectedActionDropDownLabel.Text = 'Selected Action';
            app.SelectedActionDropDown = uidropdown(app.GridLayout);
            app.SelectedActionDropDown.Items = {'Select Action', 'Calculate Potential', 'Fit Potential'};
            app.SelectedActionDropDown.ClickedFcn = createCallbackFcn(app, @SelectedActionDropDownClicked2, true);
            app.SelectedActionDropDown.Value = 'Select Action';
            app.Panel_33 = uipanel(app.GridLayout);
            app.TextArea_2 = uitextarea(app.Panel_33);
            app.TextArea_2.Value = {'Calculating your own potential file with one of the RGTFun potentials will automatically generate a fit output file'; ''; 'If your fit isn''t good, try increasing your "min r" value'};
            app.CloseButton_7 = uibutton(app.Panel_33, 'push');
            app.CloseButton_7.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_7Pushed2, true);
            app.CloseButton_7.Text = 'Close';
            app.TipsLabel_2 = uilabel(app.Panel_33);
            app.TipsLabel_2.Text = 'Tips';
            app.ScatteringTab = uitab(app.TabGroup);
            app.ScatteringTab.Title = 'Scattering';
            app.GridLayout2 = uigridlayout(app.ScatteringTab);
            app.Panel_7 = uipanel(app.GridLayout2);
            app.minEeVEditFieldLabel = uilabel(app.Panel_7);
            app.minEeVEditFieldLabel.Text = 'min E (eV)';
            app.minEeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.ScatteringIntegralsLabel = uilabel(app.Panel_7);
            app.ScatteringIntegralsLabel.Text = 'Scattering Integrals';
            app.maxEeVEditFieldLabel = uilabel(app.Panel_7);
            app.maxEeVEditFieldLabel.Text = 'max E (eV)';
            app.maxEeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.Panel_17 = uipanel(app.Panel_7);
            app.z1EditFieldLabel = uilabel(app.Panel_17);
            app.z1EditFieldLabel.Text = 'z1';
            app.z1EditField = uieditfield(app.Panel_17, 'numeric');
            app.z2EditFieldLabel = uilabel(app.Panel_17);
            app.z2EditFieldLabel.Text = 'z2';
            app.z2EditField = uieditfield(app.Panel_17, 'numeric');
            app.thetamaxradEditFieldLabel = uilabel(app.Panel_17);
            app.thetamaxradEditFieldLabel.Text = 'theta max (rad)';
            app.thetamaxradEditField = uieditfield(app.Panel_17, 'numeric');
            app.thetastepradEditFieldLabel = uilabel(app.Panel_17);
            app.thetastepradEditFieldLabel.Text = 'theta step (rad)';
            app.thetastepradEditField = uieditfield(app.Panel_17, 'numeric');
            app.thetaminradEditFieldLabel = uilabel(app.Panel_17);
            app.thetaminradEditFieldLabel.Text = 'theta min (rad)';
            app.thetaminradEditField = uieditfield(app.Panel_17, 'numeric');
            app.Panel_16 = uipanel(app.Panel_7);
            app.SpecifyFitOutputFileLabel = uilabel(app.Panel_16);
            app.SpecifyFitOutputFileLabel.Text = 'Specify Fit Output File';
            app.SelectFileButton = uibutton(app.Panel_16, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectedPathEditField_15Label = uilabel(app.Panel_16);
            app.SelectedPathEditField_15Label.Text = 'Selected Path';
            app.SelectedPathEditField_15 = uieditfield(app.Panel_16, 'text');
            app.bminAEditFieldLabel = uilabel(app.Panel_16);
            app.bminAEditFieldLabel.Text = 'b min (A)';
            app.bminAEditField = uieditfield(app.Panel_16, 'numeric');
            app.bmaxAEditFieldLabel = uilabel(app.Panel_16);
            app.bmaxAEditFieldLabel.Text = 'b max (A)';
            app.bmaxAEditField = uieditfield(app.Panel_16, 'numeric');
            app.SpecifyRootFindingRangeforRminLabel = uilabel(app.Panel_16);
            app.SpecifyRootFindingRangeforRminLabel.Text = 'Specify Root Finding Range for Rmin';
            app.minRminALabel = uilabel(app.Panel_16);
            app.minRminALabel.Text = 'min Rmin (A)';
            app.minRminAEditField = uieditfield(app.Panel_16, 'numeric');
            app.minRminAEditField.ValueChangedFcn = createCallbackFcn(app, @minRminAEditFieldValueChanged, true);
            app.maxRminALabel = uilabel(app.Panel_16);
            app.maxRminALabel.Text = 'max Rmin (A)';
            app.maxRminAEditField = uieditfield(app.Panel_16, 'numeric');
            app.LogspacingCheckBox_3 = uicheckbox(app.Panel_16);
            app.LogspacingCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @LogspacingCheckBox_3ValueChanged, true);
            app.LogspacingCheckBox_3.Text = 'Log spacing';
            app.Panel_38 = uipanel(app.Panel_16);
            app.bstepAEditFieldLabel = uilabel(app.Panel_38);
            app.bstepAEditFieldLabel.Text = 'b step (A)';
            app.bstepAEditField = uieditfield(app.Panel_38, 'numeric');
            app.Panel_37 = uipanel(app.Panel_16);
            app.numberofptsEditField_3Label = uilabel(app.Panel_37);
            app.numberofptsEditField_3Label.Text = 'number of pts';
            app.numberofptsEditField_3 = uieditfield(app.Panel_37, 'numeric');
            app.LogspacingCheckBox = uicheckbox(app.Panel_7);
            app.LogspacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogspacingCheckBoxValueChanged, true);
            app.LogspacingCheckBox.Text = 'Log spacing';
            app.orLabel = uilabel(app.Panel_7);
            app.orLabel.Text = 'or';
            app.Panel_23 = uipanel(app.Panel_7);
            app.EstepeVEditFieldLabel = uilabel(app.Panel_23);
            app.EstepeVEditFieldLabel.Text = 'E step (eV)';
            app.EstepeVEditField = uieditfield(app.Panel_23, 'numeric');
            app.Panel_24 = uipanel(app.Panel_7);
            app.numberofptsEditFieldLabel = uilabel(app.Panel_24);
            app.numberofptsEditFieldLabel.Text = 'number of pts';
            app.numberofptsEditField = uieditfield(app.Panel_24, 'numeric');
            app.ExecuteButton_3 = uibutton(app.GridLayout2, 'push');
            app.ExecuteButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_3Pushed, true);
            app.ExecuteButton_3.Text = 'Execute';
            app.TabGroup2_3 = uitabgroup(app.GridLayout2);
            app.DistofClosestApproachTab = uitab(app.TabGroup2_3);
            app.DistofClosestApproachTab.Title = 'Dist. of Closest Approach';
            app.UIAxes_7 = uiaxes(app.DistofClosestApproachTab);
            title(app.UIAxes_7, 'Distance of Closest Approach')
            xlabel(app.UIAxes_7, 'Impact Parameter (Angstrom)')
            ylabel(app.UIAxes_7, 'Distance (Angstrom)')
            zlabel(app.UIAxes_7, 'Z')
            app.ScatteringAngleTab = uitab(app.TabGroup2_3);
            app.ScatteringAngleTab.Title = 'Scattering Angle';
            app.UIAxes_5 = uiaxes(app.ScatteringAngleTab);
            title(app.UIAxes_5, 'Scattering Angle')
            xlabel(app.UIAxes_5, 'Impact Parameter (Angstrom)')
            ylabel(app.UIAxes_5, 'Scattering Angle (rad)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.WriteScatteringInputFileButton = uibutton(app.GridLayout2, 'push');
            app.WriteScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteScatteringInputFileButtonPushed, true);
            app.WriteScatteringInputFileButton.Text = 'Write Scattering Input File';
            app.OrselectyourownscatteringinputfileLabel = uilabel(app.GridLayout2);
            app.OrselectyourownscatteringinputfileLabel.Text = 'Or select your own scattering input file';
            app.SelectScatteringInputFileButton = uibutton(app.GridLayout2, 'push');
            app.SelectScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectScatteringInputFileButtonPushed, true);
            app.SelectScatteringInputFileButton.Text = 'Select Scattering Input File';
            app.SelectFolderButton_5 = uibutton(app.GridLayout2, 'push');
            app.SelectFolderButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_5Pushed, true);
            app.SelectFolderButton_5.Text = 'Select Folder';
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_2 = uilabel(app.GridLayout2);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_2.Text = 'Specify folder to save input/output files and data for this study';
            app.SelectLocationofRGTFunFolderButton_2 = uibutton(app.GridLayout2, 'push');
            app.SelectLocationofRGTFunFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButton_2Pushed, true);
            app.SelectLocationofRGTFunFolderButton_2.Text = 'Select Location of RGTFun Folder';
            app.Panel_32 = uipanel(app.GridLayout2);
            app.TextArea = uitextarea(app.Panel_32);
            app.TextArea.Value = {'Make sure your "min Rmin" value for root finding is greater than 1!'; ''; 'If you are using a LJ potential you should use a "min E" of 0.01 so that the transport coefficient integrals will converge'; ''; 'Note that Center of Mass energy should be entered'};
            app.CloseButton_6 = uibutton(app.Panel_32, 'push');
            app.CloseButton_6.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_6Pushed, true);
            app.CloseButton_6.Text = 'Close';
            app.TipsLabel = uilabel(app.Panel_32);
            app.TipsLabel.Text = 'Tips';
            app.SelectLocationofChebfunFolderButton = uibutton(app.GridLayout2, 'push');
            app.SelectLocationofChebfunFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofChebfunFolderButtonPushed, true);
            app.SelectLocationofChebfunFolderButton.Text = 'Select Location of Chebfun Folder';
            app.Panel_28 = uipanel(app.GridLayout2);
            app.PleaseselectlocationofRGTFunFolderLabel_2 = uilabel(app.Panel_28);
            app.PleaseselectlocationofRGTFunFolderLabel_2.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_7 = uibutton(app.Panel_28, 'push');
            app.SelectFolderButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_7Pushed, true);
            app.SelectFolderButton_7.Text = 'Select Folder ';
            app.SelectedFolderEditField_7Label = uilabel(app.Panel_28);
            app.SelectedFolderEditField_7Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_7 = uieditfield(app.Panel_28, 'text');
            app.ConfirmButton_3 = uibutton(app.Panel_28, 'push');
            app.ConfirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_3Pushed, true);
            app.ConfirmButton_3.Text = 'Confirm';
            app.Panel_45 = uipanel(app.GridLayout2);
            app.PleaseselectlocationofChebfunFolderLabel = uilabel(app.Panel_45);
            app.PleaseselectlocationofChebfunFolderLabel.Text = 'Please select location of Chebfun Folder';
            app.SelectFolderButton_13 = uibutton(app.Panel_45, 'push');
            app.SelectFolderButton_13.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_13Pushed, true);
            app.SelectFolderButton_13.Text = 'Select Folder ';
            app.SelectedFolderEditField_13Label = uilabel(app.Panel_45);
            app.SelectedFolderEditField_13Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_13 = uieditfield(app.Panel_45, 'text');
            app.ConfirmButton_2 = uibutton(app.Panel_45, 'push');
            app.ConfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_2Pushed, true);
            app.ConfirmButton_2.Text = 'Confirm';
            app.Panel_47 = uipanel(app.GridLayout2);
            app.CalculateScatteringIntegralsLabel = uilabel(app.Panel_47);
            app.CalculateScatteringIntegralsLabel.Text = 'Calculate Scattering Integrals';
            app.CalculatethedistanceofclosestapproachDOCAandscatteringangleasafunctionofimpactparameterLabel = uilabel(app.Panel_47);
            app.CalculatethedistanceofclosestapproachDOCAandscatteringangleasafunctionofimpactparameterLabel.Text = 'Calculate the distance of closest approach (DOCA) and scattering angle as a function of impact parameter.';
            app.Image2_2 = uiimage(app.GridLayout2);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2_2Clicked, true);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel = uilabel(app.GridLayout2);
            app.ClickTheGuideforhelpLabel.Text = 'Click The Guide for help!';
            app.SelectedFileEditFieldLabel = uilabel(app.GridLayout2);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.GridLayout2, 'text');
            app.SelectedFolderEditField_5Label = uilabel(app.GridLayout2);
            app.SelectedFolderEditField_5Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_5 = uieditfield(app.GridLayout2, 'text');
            app.IntegralTypeDropDownLabel = uilabel(app.GridLayout2);
            app.IntegralTypeDropDownLabel.Text = 'Integral Type';
            app.IntegralTypeDropDown = uidropdown(app.GridLayout2);
            app.IntegralTypeDropDown.Items = {'Select Integral Type', 'Exact Coulomb', 'Numerical'};
            app.IntegralTypeDropDown.ClickedFcn = createCallbackFcn(app, @IntegralTypeDropDownClicked, true);
            app.IntegralTypeDropDown.Value = 'Select Integral Type';
            app.CrossSectionsTab = uitab(app.TabGroup);
            app.CrossSectionsTab.Title = 'Cross Sections';
            app.GridLayout3 = uigridlayout(app.CrossSectionsTab);
            app.TabGroup2_4 = uitabgroup(app.GridLayout3);
            app.TotalCrossSectionTab = uitab(app.TabGroup2_4);
            app.TotalCrossSectionTab.Title = 'Total Cross Section';
            app.UIAxes_15 = uiaxes(app.TotalCrossSectionTab);
            title(app.UIAxes_15, 'Total Cross Section')
            xlabel(app.UIAxes_15, 'Energy')
            zlabel(app.UIAxes_15, 'Z')
            app.DiffusionCrossSectionTab = uitab(app.TabGroup2_4);
            app.DiffusionCrossSectionTab.Title = 'Diffusion Cross Section';
            app.UIAxes_8 = uiaxes(app.DiffusionCrossSectionTab);
            title(app.UIAxes_8, 'Diffusion Cross Section')
            xlabel(app.UIAxes_8, 'Energy')
            zlabel(app.UIAxes_8, 'Z')
            app.ViscosityCrossSectionTab = uitab(app.TabGroup2_4);
            app.ViscosityCrossSectionTab.Title = 'Viscosity Cross Section';
            app.UIAxes_9 = uiaxes(app.ViscosityCrossSectionTab);
            title(app.UIAxes_9, 'Viscosity Cross Section')
            xlabel(app.UIAxes_9, 'Scattering Parameter')
            zlabel(app.UIAxes_9, 'Z')
            app.StoppingCrossSectionTab = uitab(app.TabGroup2_4);
            app.StoppingCrossSectionTab.Title = 'Stopping Cross Section';
            app.UIAxes_13 = uiaxes(app.StoppingCrossSectionTab);
            title(app.UIAxes_13, 'Stopping Cross Section')
            xlabel(app.UIAxes_13, 'Scattering Parameter')
            zlabel(app.UIAxes_13, 'Z')
            app.Panel_8 = uipanel(app.GridLayout3);
            app.minEeVEditField_2Label = uilabel(app.Panel_8);
            app.minEeVEditField_2Label.Text = 'min E (eV)';
            app.minEeVEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.TransportCrossSectionLabel = uilabel(app.Panel_8);
            app.TransportCrossSectionLabel.Text = 'Transport Cross Section';
            app.maxEeVEditField_2Label = uilabel(app.Panel_8);
            app.maxEeVEditField_2Label.Text = 'max E (eV)';
            app.maxEeVEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.Panel_20 = uipanel(app.Panel_8);
            app.welldeptheVEditFieldLabel = uilabel(app.Panel_20);
            app.welldeptheVEditFieldLabel.Text = 'well depth (eV)';
            app.welldeptheVEditField = uieditfield(app.Panel_20, 'numeric');
            app.Panel_21 = uipanel(app.Panel_8);
            app.SelectImpactParamvsScatterAngleDataFileLabel = uilabel(app.Panel_21);
            app.SelectImpactParamvsScatterAngleDataFileLabel.Text = 'Select Impact Param vs Scatter Angle Data File';
            app.SelectPathButton_7 = uibutton(app.Panel_21, 'push');
            app.SelectPathButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_7Pushed, true);
            app.SelectPathButton_7.Text = 'Select Path';
            app.SelectedPathEditField_16Label = uilabel(app.Panel_21);
            app.SelectedPathEditField_16Label.Text = 'Selected Path';
            app.SelectedPathEditField_16 = uieditfield(app.Panel_21, 'text');
            app.m1amuEditField_2Label = uilabel(app.Panel_8);
            app.m1amuEditField_2Label.Text = 'm1 (amu)';
            app.m1amuEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.m2amuEditField_2Label = uilabel(app.Panel_8);
            app.m2amuEditField_2Label.Text = 'm2 (amu)';
            app.m2amuEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.LogspacingCheckBox_2 = uicheckbox(app.Panel_8);
            app.LogspacingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LogspacingCheckBox_2ValueChanged, true);
            app.LogspacingCheckBox_2.Text = 'Log spacing';
            app.orLabel_2 = uilabel(app.Panel_8);
            app.orLabel_2.Text = 'or';
            app.Panel_26 = uipanel(app.Panel_8);
            app.EstepeVEditField_2Label = uilabel(app.Panel_26);
            app.EstepeVEditField_2Label.Text = 'E step (eV)';
            app.EstepeVEditField_2 = uieditfield(app.Panel_26, 'numeric');
            app.Panel_25 = uipanel(app.Panel_8);
            app.numberofptsEditField_2Label = uilabel(app.Panel_25);
            app.numberofptsEditField_2Label.Text = 'number of pts';
            app.numberofptsEditField_2 = uieditfield(app.Panel_25, 'numeric');
            app.TotalCrossSectionCutoffDropDownLabel = uilabel(app.Panel_8);
            app.TotalCrossSectionCutoffDropDownLabel.Text = 'Total Cross Section Cutoff';
            app.TotalCrossSectionCutoffDropDown = uidropdown(app.Panel_8);
            app.TotalCrossSectionCutoffDropDown.Items = {'Select Cutoff', 'Manual', 'Quantum'};
            app.TotalCrossSectionCutoffDropDown.ClickedFcn = createCallbackFcn(app, @TotalCrossSectionCutoffDropDownClicked, true);
            app.TotalCrossSectionCutoffDropDown.Value = 'Select Cutoff';
            app.Panel_41 = uipanel(app.Panel_8);
            app.thetamaxradEditField_2Label = uilabel(app.Panel_41);
            app.thetamaxradEditField_2Label.Text = 'theta max (rad)';
            app.thetamaxradEditField_2 = uieditfield(app.Panel_41, 'numeric');
            app.Panel_42 = uipanel(app.Panel_8);
            app.SelectDOCAfileButton = uibutton(app.Panel_42, 'push');
            app.SelectDOCAfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDOCAfileButtonPushed, true);
            app.SelectDOCAfileButton.Text = 'Select DOCA file';
            app.SelectedPathEditField_17 = uieditfield(app.Panel_42, 'text');
            app.ExecuteButton_4 = uibutton(app.GridLayout3, 'push');
            app.ExecuteButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_4Pushed, true);
            app.ExecuteButton_4.Text = 'Execute';
            app.WriteCSInputFileButton = uibutton(app.GridLayout3, 'push');
            app.WriteCSInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteCSInputFileButtonPushed, true);
            app.WriteCSInputFileButton.Text = 'Write CS Input File';
            app.OrselectyourowntransportinputfileLabel = uilabel(app.GridLayout3);
            app.OrselectyourowntransportinputfileLabel.Text = 'Or select your own transport input file';
            app.SelectTransportInputFileButton = uibutton(app.GridLayout3, 'push');
            app.SelectTransportInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTransportInputFileButtonPushed, true);
            app.SelectTransportInputFileButton.Text = 'Select Transport Input File';
            app.SelectFolderButton_4 = uibutton(app.GridLayout3, 'push');
            app.SelectFolderButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_4Pushed, true);
            app.SelectFolderButton_4.Text = 'Select Folder';
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_3 = uilabel(app.GridLayout3);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_3.Text = 'Specify folder to save input/output files and data for this study';
            app.SelectLocationofRGTFunFolderButton_3 = uibutton(app.GridLayout3, 'push');
            app.SelectLocationofRGTFunFolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButton_3Pushed, true);
            app.SelectLocationofRGTFunFolderButton_3.Text = 'Select Location of RGTFun Folder';
            app.Panel_29 = uipanel(app.GridLayout3);
            app.PleaseselectlocationofRGTFunFolderLabel_3 = uilabel(app.Panel_29);
            app.PleaseselectlocationofRGTFunFolderLabel_3.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_8 = uibutton(app.Panel_29, 'push');
            app.SelectFolderButton_8.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_8Pushed, true);
            app.SelectFolderButton_8.Text = 'Select Folder ';
            app.SelectedFolderEditField_8Label = uilabel(app.Panel_29);
            app.SelectedFolderEditField_8Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_8 = uieditfield(app.Panel_29, 'text');
            app.ConfirmButton_4 = uibutton(app.Panel_29, 'push');
            app.ConfirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_4Pushed, true);
            app.ConfirmButton_4.Text = 'Confirm';
            app.Panel_34 = uipanel(app.GridLayout3);
            app.TextArea_3 = uitextarea(app.Panel_34);
            app.TextArea_3.Value = {'Your energy values should be the same as the energies you specified in the previous tab and be CM energy'; ''; 'Note that diffusion CS, viscosity CS, and total CS are saved as a function of CM energy, while stopping CS is saved as a function of lab frame energy'};
            app.CloseButton_8 = uibutton(app.Panel_34, 'push');
            app.CloseButton_8.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_8Pushed, true);
            app.CloseButton_8.Text = 'Close';
            app.TipsLabel_3 = uilabel(app.Panel_34);
            app.TipsLabel_3.Text = 'Tips';
            app.Panel_48 = uipanel(app.GridLayout3);
            app.CalculateCrossSectionsLabel = uilabel(app.Panel_48);
            app.CalculateCrossSectionsLabel.Text = 'Calculate Cross Sections';
            app.CalculatetotalcrosssectiondiffusioncrosssectionviscositycrosssectionandstoppingcrosssectionLabel = uilabel(app.Panel_48);
            app.CalculatetotalcrosssectiondiffusioncrosssectionviscositycrosssectionandstoppingcrosssectionLabel.Text = 'Calculate total cross section, diffusion cross section, viscosity cross section, and stopping cross section.';
            app.Image2_3 = uiimage(app.GridLayout3);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @Image2_3Clicked, true);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel_3 = uilabel(app.GridLayout3);
            app.ClickTheGuideforhelpLabel_3.Text = 'Click The Guide for help!';
            app.SelectedFileEditField_2Label = uilabel(app.GridLayout3);
            app.SelectedFileEditField_2Label.Text = 'Selected File';
            app.SelectedFileEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.SelectedFolderEditField_4Label = uilabel(app.GridLayout3);
            app.SelectedFolderEditField_4Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_4 = uieditfield(app.GridLayout3, 'text');
            app.IntegralTypeDropDown_2Label = uilabel(app.GridLayout3);
            app.IntegralTypeDropDown_2Label.Text = 'Integral Type';
            app.IntegralTypeDropDown_2 = uidropdown(app.GridLayout3);
            app.IntegralTypeDropDown_2.Items = {'Select Integral Type', 'Exact LJ', 'Numerical'};
            app.IntegralTypeDropDown_2.ClickedFcn = createCallbackFcn(app, @IntegralTypeDropDown_2Clicked, true);
            app.IntegralTypeDropDown_2.Value = 'Select Integral Type';
            app.TransportTab = uitab(app.TabGroup);
            app.TransportTab.Title = 'Transport';
            app.GridLayout4 = uigridlayout(app.TransportTab);
            app.Panel_9 = uipanel(app.GridLayout4);
            app.minTKEditFieldLabel = uilabel(app.Panel_9);
            app.minTKEditFieldLabel.Text = 'min T (K)';
            app.minTKEditField = uieditfield(app.Panel_9, 'numeric');
            app.maxTKEditFieldLabel = uilabel(app.Panel_9);
            app.maxTKEditFieldLabel.Text = 'max T (K)';
            app.maxTKEditField = uieditfield(app.Panel_9, 'numeric');
            app.TstepKEditFieldLabel = uilabel(app.Panel_9);
            app.TstepKEditFieldLabel.Text = 'T step (K)';
            app.TstepKEditField = uieditfield(app.Panel_9, 'numeric');
            app.m1amuEditFieldLabel = uilabel(app.Panel_9);
            app.m1amuEditFieldLabel.Text = 'm1 (amu)';
            app.m1amuEditField = uieditfield(app.Panel_9, 'numeric');
            app.m2amuEditFieldLabel = uilabel(app.Panel_9);
            app.m2amuEditFieldLabel.Text = 'm2 (amu)';
            app.m2amuEditField = uieditfield(app.Panel_9, 'numeric');
            app.Panel_11 = uipanel(app.Panel_9);
            app.SelectDiffusionCSDataButton = uibutton(app.Panel_11, 'push');
            app.SelectDiffusionCSDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDiffusionCSDataButtonPushed, true);
            app.SelectDiffusionCSDataButton.Text = 'Select Diffusion CS Data';
            app.SelectedFileEditField_4Label = uilabel(app.Panel_11);
            app.SelectedFileEditField_4Label.Text = 'Selected File';
            app.SelectedFileEditField_4 = uieditfield(app.Panel_11, 'text');
            app.SelectViscosityCSDataButton = uibutton(app.Panel_11, 'push');
            app.SelectViscosityCSDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectViscosityCSDataButtonPushed, true);
            app.SelectViscosityCSDataButton.Text = 'Select Viscosity CS Data';
            app.SelectedFileEditField_7Label = uilabel(app.Panel_11);
            app.SelectedFileEditField_7Label.Text = 'Selected File';
            app.SelectedFileEditField_7 = uieditfield(app.Panel_11, 'text');
            app.Panel_22 = uipanel(app.Panel_9);
            app.welldepthKEditFieldLabel = uilabel(app.Panel_22);
            app.welldepthKEditFieldLabel.Text = 'well depth (K)';
            app.welldepthKEditField = uieditfield(app.Panel_22, 'numeric');
            app.dangstromEditFieldLabel = uilabel(app.Panel_22);
            app.dangstromEditFieldLabel.Text = 'd (angstrom)';
            app.dangstromEditField = uieditfield(app.Panel_22, 'numeric');
            app.ExecuteButton_5 = uibutton(app.GridLayout4, 'push');
            app.ExecuteButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_5Pushed, true);
            app.ExecuteButton_5.Text = 'Execute';
            app.WriteTransportInputFileButton_2 = uibutton(app.GridLayout4, 'push');
            app.WriteTransportInputFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @WriteTransportInputFileButton_2Pushed, true);
            app.WriteTransportInputFileButton_2.Text = 'Write Transport Input File';
            app.OrselectyourowntransportinputfileLabel_2 = uilabel(app.GridLayout4);
            app.OrselectyourowntransportinputfileLabel_2.Text = 'Or select your own transport input file';
            app.SelectTransportInputFileButton_2 = uibutton(app.GridLayout4, 'push');
            app.SelectTransportInputFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectTransportInputFileButton_2Pushed, true);
            app.SelectTransportInputFileButton_2.Text = 'Select Transport Input File';
            app.SelectFolderButton_3 = uibutton(app.GridLayout4, 'push');
            app.SelectFolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_3Pushed, true);
            app.SelectFolderButton_3.Text = 'Select Folder';
            app.TabGroup2_5 = uitabgroup(app.GridLayout4);
            app.DiffusionTab = uitab(app.TabGroup2_5);
            app.DiffusionTab.Title = 'Diffusion';
            app.UIAxes_10 = uiaxes(app.DiffusionTab);
            title(app.UIAxes_10, 'Diffusion Coefficient')
            xlabel(app.UIAxes_10, 'Temperature (K)')
            zlabel(app.UIAxes_10, 'Z')
            app.ViscosityTab = uitab(app.TabGroup2_5);
            app.ViscosityTab.Title = 'Viscosity';
            app.UIAxes_16 = uiaxes(app.ViscosityTab);
            title(app.UIAxes_16, 'Viscosity Coefficient')
            xlabel(app.UIAxes_16, 'Temperature (K)')
            zlabel(app.UIAxes_16, 'Z')
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_4 = uilabel(app.GridLayout4);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_4.Text = 'Specify folder to save input/output files and data for this study';
            app.SelectLocationofRGTFunFolderButton_4 = uibutton(app.GridLayout4, 'push');
            app.SelectLocationofRGTFunFolderButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButton_4Pushed, true);
            app.SelectLocationofRGTFunFolderButton_4.Text = 'Select Location of RGTFun Folder';
            app.Panel_30 = uipanel(app.GridLayout4);
            app.PleaseselectlocationofRGTFunFolderLabel_4 = uilabel(app.Panel_30);
            app.PleaseselectlocationofRGTFunFolderLabel_4.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_9 = uibutton(app.Panel_30, 'push');
            app.SelectFolderButton_9.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_9Pushed, true);
            app.SelectFolderButton_9.Text = 'Select Folder ';
            app.SelectedFolderEditField_9Label = uilabel(app.Panel_30);
            app.SelectedFolderEditField_9Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_9 = uieditfield(app.Panel_30, 'text');
            app.ConfirmButton_5 = uibutton(app.Panel_30, 'push');
            app.ConfirmButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_5Pushed, true);
            app.ConfirmButton_5.Text = 'Confirm';
            app.Panel_35 = uipanel(app.GridLayout4);
            app.TextArea_4 = uitextarea(app.Panel_35);
            app.TextArea_4.Value = {'Make sure to select the correct cross section data!'};
            app.CloseButton_9 = uibutton(app.Panel_35, 'push');
            app.CloseButton_9.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_9Pushed, true);
            app.CloseButton_9.Text = 'Close';
            app.TipsLabel_4 = uilabel(app.Panel_35);
            app.TipsLabel_4.Text = 'Tips';
            app.Panel_49 = uipanel(app.GridLayout4);
            app.CalculateTransportCoefficientsLabel = uilabel(app.Panel_49);
            app.CalculateTransportCoefficientsLabel.Text = 'Calculate Transport Coefficients';
            app.CalculatetheselfdiffusioncoefficientandviscositycoefficientforuserspecifiedtemperaturesLabel = uilabel(app.Panel_49);
            app.CalculatetheselfdiffusioncoefficientandviscositycoefficientforuserspecifiedtemperaturesLabel.Text = 'Calculate the self-diffusion coefficient and viscosity coefficient for user-specified temperatures.';
            app.Image2_4 = uiimage(app.GridLayout4);
            app.Image2_4.ImageClickedFcn = createCallbackFcn(app, @Image2_4Clicked, true);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel_4 = uilabel(app.GridLayout4);
            app.ClickTheGuideforhelpLabel_4.Text = 'Click The Guide for help!';
            app.SelectedFileEditField_3Label = uilabel(app.GridLayout4);
            app.SelectedFileEditField_3Label.Text = 'Selected File';
            app.SelectedFileEditField_3 = uieditfield(app.GridLayout4, 'text');
            app.SelectedFolderEditField_3Label = uilabel(app.GridLayout4);
            app.SelectedFolderEditField_3Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_3 = uieditfield(app.GridLayout4, 'text');
            app.IntegrationTypeDropDownLabel = uilabel(app.GridLayout4);
            app.IntegrationTypeDropDownLabel.Text = 'Integration Type';
            app.IntegrationTypeDropDown = uidropdown(app.GridLayout4);
            app.IntegrationTypeDropDown.Items = {'Select Integration Type', 'Numerical', 'Exact LJ'};
            app.IntegrationTypeDropDown.ClickedFcn = createCallbackFcn(app, @IntegrationTypeDropDownClicked, true);
            app.IntegrationTypeDropDown.Value = 'Select Integration Type';
            app.VHSTab = uitab(app.TabGroup);
            app.VHSTab.Title = 'VHS';
            app.GridLayout5 = uigridlayout(app.VHSTab);
            app.SelectViscosityCoefDataButton = uibutton(app.GridLayout5, 'push');
            app.SelectViscosityCoefDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectViscosityCoefDataButtonPushed, true);
            app.SelectViscosityCoefDataButton.Text = 'Select Viscosity Coef Data';
            app.WriteVHSInputFileButton = uibutton(app.GridLayout5, 'push');
            app.WriteVHSInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVHSInputFileButtonPushed, true);
            app.WriteVHSInputFileButton.Text = 'Write VHS Input File';
            app.SelectFolderButton_2 = uibutton(app.GridLayout5, 'push');
            app.SelectFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_2Pushed, true);
            app.SelectFolderButton_2.Text = 'Select Folder';
            app.SpecifyNumberofSubintervalsLabel = uilabel(app.GridLayout5);
            app.SpecifyNumberofSubintervalsLabel.Text = 'Specify Number of Subintervals';
            app.ExecuteButton_6 = uibutton(app.GridLayout5, 'push');
            app.ExecuteButton_6.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_6Pushed, true);
            app.ExecuteButton_6.Text = 'Execute';
            app.OrselectyourownVHScoefinputfileLabel = uilabel(app.GridLayout5);
            app.OrselectyourownVHScoefinputfileLabel.Text = 'Or select your own VHS coef input file';
            app.SelectVHSCoefInputFileButton = uibutton(app.GridLayout5, 'push');
            app.SelectVHSCoefInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVHSCoefInputFileButtonPushed, true);
            app.SelectVHSCoefInputFileButton.Text = 'Select VHS Coef Input File';
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TabGroup2_6 = uitabgroup(app.GridLayout5);
            app.ViscosityCoefficientTab = uitab(app.TabGroup2_6);
            app.ViscosityCoefficientTab.Title = 'Viscosity Coefficient';
            app.UIAxes_12 = uiaxes(app.ViscosityCoefficientTab);
            title(app.UIAxes_12, 'Transport Coefficient')
            xlabel(app.UIAxes_12, 'Temperature (K)')
            zlabel(app.UIAxes_12, 'Z')
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_5 = uilabel(app.GridLayout5);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_5.Text = 'Specify folder to save input/output files and data for this study';
            app.SelectLocationofRGTFunFolderButton_5 = uibutton(app.GridLayout5, 'push');
            app.SelectLocationofRGTFunFolderButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButton_5Pushed, true);
            app.SelectLocationofRGTFunFolderButton_5.Text = 'Select Location of RGTFun Folder';
            app.Panel_31 = uipanel(app.GridLayout5);
            app.PleaseselectlocationofRGTFunFolderLabel_5 = uilabel(app.Panel_31);
            app.PleaseselectlocationofRGTFunFolderLabel_5.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_10 = uibutton(app.Panel_31, 'push');
            app.SelectFolderButton_10.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_10Pushed, true);
            app.SelectFolderButton_10.Text = 'Select Folder ';
            app.SelectedFolderEditField_10Label = uilabel(app.Panel_31);
            app.SelectedFolderEditField_10Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_10 = uieditfield(app.Panel_31, 'text');
            app.ConfirmButton_6 = uibutton(app.Panel_31, 'push');
            app.ConfirmButton_6.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_6Pushed, true);
            app.ConfirmButton_6.Text = 'Confirm';
            app.Panel_36 = uipanel(app.GridLayout5);
            app.TextArea_5 = uitextarea(app.Panel_36);
            app.TextArea_5.Value = {'If your n=1 fit is not correct, try raising your lowest Temp value in the previous tab'};
            app.CloseButton_10 = uibutton(app.Panel_36, 'push');
            app.CloseButton_10.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_10Pushed, true);
            app.CloseButton_10.Text = 'Close';
            app.TipsLabel_5 = uilabel(app.Panel_36);
            app.TipsLabel_5.Text = 'Tips';
            app.Panel_50 = uipanel(app.GridLayout5);
            app.CalculateVHSParametersLabel = uilabel(app.Panel_50);
            app.CalculateVHSParametersLabel.Text = 'Calculate VHS Parameters';
            app.CalculatesDSMCparametersfortheVHSmodelLabel = uilabel(app.Panel_50);
            app.CalculatesDSMCparametersfortheVHSmodelLabel.Text = 'Calculates DSMC parameters for the VHS model.';
            app.Image2_5 = uiimage(app.GridLayout5);
            app.Image2_5.ImageClickedFcn = createCallbackFcn(app, @Image2_5Clicked, true);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel_5 = uilabel(app.GridLayout5);
            app.ClickTheGuideforhelpLabel_5.Text = 'Click The Guide for help!';
            app.SelectedFileEditField_5Label = uilabel(app.GridLayout5);
            app.SelectedFileEditField_5Label.Text = 'Selected File';
            app.SelectedFileEditField_5 = uieditfield(app.GridLayout5, 'text');
            app.NEditFieldLabel = uilabel(app.GridLayout5);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SelectedFolderEditField_2Label = uilabel(app.GridLayout5);
            app.SelectedFolderEditField_2Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_2 = uieditfield(app.GridLayout5, 'text');
            app.SelectedFileEditField_6Label = uilabel(app.GridLayout5);
            app.SelectedFileEditField_6Label.Text = 'Selected File';
            app.SelectedFileEditField_6 = uieditfield(app.GridLayout5, 'text');
            app.tolEditFieldLabel = uilabel(app.GridLayout5);
            app.tolEditFieldLabel.Text = 'tol';
            app.tolEditField = uieditfield(app.GridLayout5, 'numeric');
            app.m1amuEditField_3Label = uilabel(app.GridLayout5);
            app.m1amuEditField_3Label.Text = 'm1 (amu)';
            app.m1amuEditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.m2amuEditField_3Label = uilabel(app.GridLayout5);
            app.m2amuEditField_3Label.Text = 'm2 (amu)';
            app.m2amuEditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.VSSTab = uitab(app.TabGroup);
            app.VSSTab.Title = 'VSS';
            app.GridLayout6 = uigridlayout(app.VSSTab);
            app.WriteVSSInputFileButton = uibutton(app.GridLayout6, 'push');
            app.WriteVSSInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVSSInputFileButtonPushed, true);
            app.WriteVSSInputFileButton.Text = 'Write VSS Input File';
            app.SelectFolderButton_11 = uibutton(app.GridLayout6, 'push');
            app.SelectFolderButton_11.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_11Pushed, true);
            app.SelectFolderButton_11.Text = 'Select Folder';
            app.ExecuteButton_7 = uibutton(app.GridLayout6, 'push');
            app.ExecuteButton_7.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_7Pushed, true);
            app.ExecuteButton_7.Text = 'Execute';
            app.OrselectyourownVSScoefinputfileLabel = uilabel(app.GridLayout6);
            app.OrselectyourownVSScoefinputfileLabel.Text = 'Or select your own VSS coef input file';
            app.SelectVSSCoefInputFileButton = uibutton(app.GridLayout6, 'push');
            app.SelectVSSCoefInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVSSCoefInputFileButtonPushed, true);
            app.SelectVSSCoefInputFileButton.Text = 'Select VSS Coef Input File';
            app.UITable_2 = uitable(app.GridLayout6);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_6 = uilabel(app.GridLayout6);
            app.SpecifyfoldertosaveinputoutputfilesanddataforthisstudyLabel_6.Text = 'Specify folder to save input/output files and data for this study';
            app.SelectLocationofRGTFunFolderButton_6 = uibutton(app.GridLayout6, 'push');
            app.SelectLocationofRGTFunFolderButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationofRGTFunFolderButton_6Pushed, true);
            app.SelectLocationofRGTFunFolderButton_6.Text = 'Select Location of RGTFun Folder';
            app.Panel_43 = uipanel(app.GridLayout6);
            app.PleaseselectlocationofRGTFunFolderLabel_6 = uilabel(app.Panel_43);
            app.PleaseselectlocationofRGTFunFolderLabel_6.Text = 'Please select location of RGTFun Folder';
            app.SelectFolderButton_12 = uibutton(app.Panel_43, 'push');
            app.SelectFolderButton_12.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_12Pushed, true);
            app.SelectFolderButton_12.Text = 'Select Folder ';
            app.SelectedFolderEditField_12Label = uilabel(app.Panel_43);
            app.SelectedFolderEditField_12Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_12 = uieditfield(app.Panel_43, 'text');
            app.ConfirmButton_7 = uibutton(app.Panel_43, 'push');
            app.ConfirmButton_7.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_7Pushed, true);
            app.ConfirmButton_7.Text = 'Confirm';
            app.Panel_44 = uipanel(app.GridLayout6);
            app.TextArea_6 = uitextarea(app.Panel_44);
            app.TextArea_6.Value = {'Alpha guess should be between 1 and 2. '};
            app.CloseButton_12 = uibutton(app.Panel_44, 'push');
            app.CloseButton_12.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_12Pushed, true);
            app.CloseButton_12.Text = 'Close';
            app.TipsLabel_6 = uilabel(app.Panel_44);
            app.TipsLabel_6.Text = 'Tips';
            app.SelectDiffusionCoefDataButton = uibutton(app.GridLayout6, 'push');
            app.SelectDiffusionCoefDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDiffusionCoefDataButtonPushed, true);
            app.SelectDiffusionCoefDataButton.Text = 'Select Diffusion Coef Data';
            app.SelectVHSCoefTableButton = uibutton(app.GridLayout6, 'push');
            app.SelectVHSCoefTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVHSCoefTableButtonPushed, true);
            app.SelectVHSCoefTableButton.Text = 'Select VHS Coef Table';
            app.Panel_51 = uipanel(app.GridLayout6);
            app.CalculateVSSParametersLabel = uilabel(app.Panel_51);
            app.CalculateVSSParametersLabel.Text = 'Calculate VSS Parameters';
            app.CalculatesDSMCparametersfortheVSSmodelLabel = uilabel(app.Panel_51);
            app.CalculatesDSMCparametersfortheVSSmodelLabel.Text = 'Calculates DSMC parameters for the VSS model.';
            app.Image2_6 = uiimage(app.GridLayout6);
            app.Image2_6.ImageClickedFcn = createCallbackFcn(app, @Image2_6Clicked, true);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ClickTheGuideforhelpLabel_6 = uilabel(app.GridLayout6);
            app.ClickTheGuideforhelpLabel_6.Text = 'Click The Guide for help!';
            app.SelectedFolderEditField_11Label = uilabel(app.GridLayout6);
            app.SelectedFolderEditField_11Label.Text = 'Selected Folder';
            app.SelectedFolderEditField_11 = uieditfield(app.GridLayout6, 'text');
            app.SelectedFileEditField_9Label = uilabel(app.GridLayout6);
            app.SelectedFileEditField_9Label.Text = 'Selected File';
            app.SelectedFileEditField_9 = uieditfield(app.GridLayout6, 'text');
            app.leastsquarestolEditFieldLabel = uilabel(app.GridLayout6);
            app.leastsquarestolEditFieldLabel.Text = 'least squares tol';
            app.leastsquarestolEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SelectedFileEditField_10Label = uilabel(app.GridLayout6);
            app.SelectedFileEditField_10Label.Text = 'Selected File';
            app.SelectedFileEditField_10 = uieditfield(app.GridLayout6, 'text');
            app.SelectedFileEditField_11Label = uilabel(app.GridLayout6);
            app.SelectedFileEditField_11Label.Text = 'Selected File';
            app.SelectedFileEditField_11 = uieditfield(app.GridLayout6, 'text');
            app.alphaguessEditFieldLabel = uilabel(app.GridLayout6);
            app.alphaguessEditFieldLabel.Text = 'alpha guess';
            app.alphaguessEditField = uieditfield(app.GridLayout6, 'numeric');
            app.m1amuEditField_4Label = uilabel(app.GridLayout6);
            app.m1amuEditField_4Label.Text = 'm1 (amu)';
            app.m1amuEditField_4 = uieditfield(app.GridLayout6, 'numeric');
            app.m2amuEditField_4Label = uilabel(app.GridLayout6);
            app.m2amuEditField_4Label.Text = 'm2 (amu)';
            app.m2amuEditField_4 = uieditfield(app.GridLayout6, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end